classdef matFLUX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DBSCAN                          matlab.ui.Figure
        SettingsMenu                    matlab.ui.container.Menu
        ColorsMenu                      matlab.ui.container.Menu
        CustomSizeMenu                  matlab.ui.container.Menu
        ScriptLocation                  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Button                          matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ExclNum                         matlab.ui.control.Spinner
        EnableExclusion                 matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        FieldofViewTab                  matlab.ui.container.Tab
        DataFieldofViewforMainChannelLabel  matlab.ui.control.Label
        ShowClustersOnlyCheckbox        matlab.ui.control.StateButton
        UIAxes2                         matlab.ui.control.UIAxes
        SingleClusterAnalysisTab        matlab.ui.container.Tab
        SingleAnalysisofClustersfromMainChannelLabel  matlab.ui.control.Label
        AlphaRadiusLabel                matlab.ui.control.Label
        AlphaRadiusSlider               matlab.ui.control.Slider
        AlphaShapeCheckBox              matlab.ui.control.CheckBox
        ShowHeatMap                     matlab.ui.control.Button
        EllipsoidFitCheckBox            matlab.ui.control.CheckBox
        ListBox                         matlab.ui.control.ListBox
        UIAxes5                         matlab.ui.control.UIAxes
        AlignmentTab                    matlab.ui.container.Tab
        SelecttheParentFolderofZarrFileFoldersLabel  matlab.ui.control.Label
        ZarrLabel                       matlab.ui.control.Label
        AlignmentandCorrectionFactorLabel  matlab.ui.control.Label
        ReferenceStainingDropDown       matlab.ui.control.DropDown
        ReferenceStainingDropDownLabel  matlab.ui.control.Label
        LoadBeadDataButton              matlab.ui.control.Button
        DeviationAxes                   matlab.ui.control.UIAxes
        LocalizationAxes                matlab.ui.control.UIAxes
        FieldofViewMultiTab             matlab.ui.container.Tab
        DBSCANparametersareappliedtoallchannelsLabel  matlab.ui.control.Label
        FieldofViewforMainDataandAdditionalChannelsLabel  matlab.ui.control.Label
        LoadChannelButton               matlab.ui.control.Button
        ChannelDetailsPanel             matlab.ui.container.Panel
        ChannelNameLabel                matlab.ui.control.Label
        ChannelFileLabel                matlab.ui.control.Label
        DBSCANCheckBox                  matlab.ui.control.CheckBox
        ChannelEnableCheckbox           matlab.ui.control.CheckBox
        zLabel                          matlab.ui.control.Label
        yLabel                          matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        XCorrectionField                matlab.ui.control.NumericEditField
        YCorrectionField                matlab.ui.control.NumericEditField
        ZCorrectionField                matlab.ui.control.NumericEditField
        ChannelListBox                  matlab.ui.control.ListBox
        ExportasmattifButton            matlab.ui.control.Button
        UIAxes7                         matlab.ui.control.UIAxes
        ClusterAnalysisMultiTab         matlab.ui.container.Tab
        ChannelsonoffLabel              matlab.ui.control.Label
        AnalyzesingleclustersinmultiplecolorsLabel  matlab.ui.control.Label
        SingleClusterAnalysisMultiChannelLabel  matlab.ui.control.Label
        Ch5CheckBox                     matlab.ui.control.CheckBox
        Ch4CheckBox                     matlab.ui.control.CheckBox
        Ch3CheckBox                     matlab.ui.control.CheckBox
        Ch2CheckBox                     matlab.ui.control.CheckBox
        AlphaRadiusLabel_2              matlab.ui.control.Label
        AlphaRadiusSlider_2             matlab.ui.control.Slider
        AlphaShapeCheckBox_2            matlab.ui.control.CheckBox
        EllipsoidFitCheckBox_2          matlab.ui.control.CheckBox
        ListBox_2                       matlab.ui.control.ListBox
        UIAxes5_2                       matlab.ui.control.UIAxes
        ConcentricalAnalysisTab         matlab.ui.container.Tab
        HistogramofMainDataandLineProfilesfromaddChannelsLabel  matlab.ui.control.Label
        ConcentricalAnalysisLabel       matlab.ui.control.Label
        NormalizeIndividuallyCheckBox   matlab.ui.control.CheckBox
        ListBox2                        matlab.ui.control.ListBox
        ButtonGroup1                    matlab.ui.container.ButtonGroup
        trcButton1                      matlab.ui.control.ToggleButton
        locButton1                      matlab.ui.control.ToggleButton
        MeanDistLabel                   matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        PolarPlotTab                    matlab.ui.container.Tab
        PolarPlotofMainDataandAdditionalChannelsLabel  matlab.ui.control.Label
        MeanDistLabel2                  matlab.ui.control.Label
        PolarPlotLabel                  matlab.ui.control.Label
        ColorCheck                      matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        ListBox2_2                      matlab.ui.control.ListBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        trcButton                       matlab.ui.control.ToggleButton
        locButton                       matlab.ui.control.ToggleButton
        NumbersTab                      matlab.ui.container.Tab
        SingleClustersinNumbersLabel    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        NumbersmultiTab                 matlab.ui.container.Tab
        SingleClustersinNumbersmultichannelLabel  matlab.ui.control.Label
        SaveNumMultCSVButton            matlab.ui.control.Button
        UITableMulti                    matlab.ui.control.Table
        AcqParametersTab                matlab.ui.container.Tab
        MINFLUXAcquisitionParametersLabel  matlab.ui.control.Label
        LocalizationsovertimeLabel      matlab.ui.control.Label
        LocalizationsperTraceLabel      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        LocalizationPrecisionTab        matlab.ui.container.Tab
        LocalizationPrecisionofLoadedDataLabel  matlab.ui.control.Label
        SaveLocPrecPDFButton            matlab.ui.control.Button
        FileNameLabel1                  matlab.ui.control.Label
        MinPtsSpinner                   matlab.ui.control.Spinner
        MinPtsLabel                     matlab.ui.control.Label
        EpsilonSpinner                  matlab.ui.control.Spinner
        EpsilonLabel                    matlab.ui.control.Label
        LoadNewButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        LoadedData1     
        Data1 
        Clusters1 
        IsNoise1 
        filtered_data
        filename %
        Minflux 
        ClusterColors
        LoadedData2   
        Data2
        LoadedData3     
        Data3
        epsilon
        minPts
        ClusterVolumes
        ClusterSurfaces
        allCenteredPoints
        Data2_off
        Data3_off
        XLimit_UIA2 double
        YLimit_UIA2 double
        ZLimit_UIA2 double
        PRM_traces_filt
        Pax
        Clusters2
        IsNoise2
        Clusters3
        IsNoise3
        Data1_off
        Data4
        Data4_off
        LoadedData4
        Data5
        Data5_off
        LoadedData5
        IsNoise4
        IsNoise5
        Clusters4
        Clusters5
        Zarr % Description
        BeadAlignmentData
        validatedBeads
        tid1
        tim1
        tid2
        tim2
        tid3
        tim3
        tid4
        tim4
        tid5
        tim5
        trace_data1
        trace_data2
        trace_data3
        trace_data4
        trace_data5
        trace_clusters1
        trace_clusters2
        trace_clusters3
        trace_clusters4
        trace_clusters5
        ClusterRDFs % Description
        ClusterRadii % Description
        filename1
        filename2
        filename3
        filename4
        filename5
        filepath1
        filepath2
        filepath3
        filepath4
        filepath5
        figureHandle3D  % Handle for 3D heatmap figure
        figureHandle2D
        AveragedParticleData
        ChannelCorrections
        ChannelFileNames
        ChannelEnableStates
        DBSCANCache struct = struct('Channel_1', struct(), 'Channel_2', struct(), 'Channel_3', struct(), 'Channel_4', struct(), 'Channel_5', struct())
        GlobalXLim
        GlobalYLim
        GlobalZLim
        GlobalXTicks
        GlobalYTicks
        GlobalZTicks
        ChannelColors cell = {'k', 'cyan', 'red', 'magenta', 'green'}
        ChannelColorButtons
        BoxSizeX = 500;  
        BoxSizeY = 500;  
        BoxSizeZ = 500; 
        UseCustomColors logical = false
        CorrectionData
        currentcolormap = 'parula';
        ShowWelcomeMessage (1,1) logical = true;
        CustomZarrScriptPath
    end
    methods (Access = private)
        function loadDataset(app, datasetNumber)
            [file, path] = uigetfile('*.mat', 'Select a MATLAB data file');
            if isequal(file, 0)
                return;
            end

            fullPath = fullfile(path, file);
            [~, filename, ~] = fileparts(file);

            if isempty(app.(['filename', num2str(datasetNumber)]))
                app.(['filename', num2str(datasetNumber)]) = filename;
            end
            app.(['filepath', num2str(datasetNumber)]) = path;  % Store the file path

            % Load data
            loadedData = load(fullPath);
            app.(['LoadedData', num2str(datasetNumber)]) = loadedData;

            % Check if the loaded data contains the expected fields
            if ~isfield(loadedData, 'itr') || ~isfield(loadedData.itr, 'loc')
                errordlg(['The loaded file does not contain the expected data structure. Please check the file: ', file], 'Data Structure Error');
                return;
            end

            % Process data
            itr = loadedData.itr;
            data = itr.loc(:,10,:) * 1e9;  % Convert to nm
            data = reshape(data, [], 3);

            data(:,2) = 1 * (data(:,2));
            % Apply z-correction factor (0.7) for all datasets
            data(:,3) = 1 * (data(:,3) * 0.7);  % Negate the z values after applying the correction factor

            app.(['Data', num2str(datasetNumber)]) = data;

            % Store tid and tim if they exist in the loaded data
            if isfield(loadedData, 'tid')
                app.(['tid', num2str(datasetNumber)]) = loadedData.tid;
            else
                warning('tid not found in the loaded data for dataset %d', datasetNumber);
            end

            if isfield(loadedData, 'tim')
                app.(['tim', num2str(datasetNumber)]) = loadedData.tim;
            else
                warning('tim not found in the loaded data for dataset %d', datasetNumber);
            end

            % Apply DBSCAN if checkbox is checked (for datasets 2-5)
            if app.DBSCANCheckBox.Value && datasetNumber > 1
                app.(['Clusters', num2str(datasetNumber)]) = applyDBSCAN(app, data, datasetNumber);
                app.(['IsNoise', num2str(datasetNumber)]) = (app.(['Clusters', num2str(datasetNumber)]) == -1);
            end

            channelName = ['Channel_', num2str(datasetNumber)];
            displayChannelName = ['Channel ', num2str(datasetNumber)];

            % Store filename
            app.ChannelFileNames.(channelName) = filename;

            % Initialize correction values for the new channel
            if ~isfield(app.ChannelCorrections, channelName)
                app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);
            end

            % Set the new channel to be enabled by default
            app.ChannelEnableStates.(channelName) = true;

            % Update the ChannelListBox
            if ~ismember(displayChannelName, app.ChannelListBox.Items)
                app.ChannelListBox.Items{end+1} = displayChannelName;
            end

            % Update the ChannelListBox
            updateChannelListBox(app);

            % Select the newly added channel
            app.ChannelListBox.Value = ['Channel ', num2str(datasetNumber)];

            % Update channel details
            updateChannelDetails(app, app.ChannelListBox.Value);

            % Update plots
            if datasetNumber == 1
                updatePlots(app);
            else
                updatePlots2(app);
            end
        end


        function clusters = applyDBSCAN(app, data, channelNumber)
            if nargin < 3
                channelNumber = 1; % Default to Channel 1 if not specified
            end

            epsilon = app.EpsilonSpinner.Value;
            minPts = app.MinPtsSpinner.Value;

            % Generate a unique key for the current parameters and data
            % Use a valid field name format
            cacheKey = sprintf('e%.2f_m%d_s%d', epsilon, minPts, size(data, 1));
            cacheKey = strrep(cacheKey, '.', '_'); % Replace dots with underscores
            channelKey = ['Channel_', num2str(channelNumber)];

            % Check if the result is already cached for this channel
            if isfield(app.DBSCANCache, channelKey) && isfield(app.DBSCANCache.(channelKey), cacheKey)
                clusters = app.DBSCANCache.(channelKey).(cacheKey);
                disp(['Using cached DBSCAN result for ', channelKey]);
            else
                % If not cached, perform DBSCAN
                d = uiprogressdlg(app.DBSCAN, 'Title', 'Applying DBSCAN', 'Message', ['Processing ', channelKey, '...']);
                clusters = dbscan(data, epsilon, minPts);
                close(d);

                % Cache the result for this channel
                if ~isfield(app.DBSCANCache, channelKey)
                    app.DBSCANCache.(channelKey) = struct();
                end
                app.DBSCANCache.(channelKey).(cacheKey) = clusters;
                disp(['Caching new DBSCAN result for ', channelKey]);
            end
        end

        function clearDBSCANCache(app, channelNumber)
            channelKey = ['Channel_', num2str(channelNumber)];
            app.DBSCANCache.(channelKey) = struct();
            disp(['DBSCAN cache cleared for ', channelKey]);
        end


        function updateChannelListBox(app)
            % Get all unique channel names
            channelNames = unique(cellfun(@(x) ['Channel ', num2str(x)], num2cell(1:length(fieldnames(app.ChannelFileNames))), 'UniformOutput', false));

            % Add 'Add Channel' option at the end
            channelNames{end+1} = 'Add Channel';

            % Update the ChannelListBox
            app.ChannelListBox.Items = channelNames;
        end

  function updatePlots(app)
    if isempty(app.Data1)
        return;
    end

    app.Clusters1 = applyDBSCAN(app, app.Data1, 1); % Explicitly pass channel number 1
    app.IsNoise1 = (app.Clusters1 == -1);
    valid_clusters = unique(app.Clusters1(app.Clusters1 ~= -1));

    if isempty(valid_clusters)
        % Handle the case where there are no valid clusters
        cla(app.UIAxes);
        cla(app.UIAxes2);
        app.ListBox.Items = {};
        app.ListBox_2.Items = {};
        app.ListBox2.Items = {};
        app.ListBox2_2.Items = {};
        return;
    end
    
    % Filter clusters based on the exclusion threshold if the checkbox is enabled
    if app.EnableExclusion.Value
        exclusion_threshold = app.ExclNum.Value;
        clusters_above_threshold = valid_clusters(arrayfun(@(x) sum(app.Clusters1 == x), valid_clusters) >= exclusion_threshold);
    else
        clusters_above_threshold = valid_clusters;
    end
    
    if isempty(clusters_above_threshold)
        % Handle the case where there are no clusters above the threshold
        cla(app.UIAxes);
        cla(app.UIAxes2);
        app.ListBox.Items = {};
        app.ListBox_2.Items = {};
        app.ListBox2.Items = {};
        app.ListBox2_2.Items = {};
        return;
    end
    
    % Update UI elements
    updateUIElements(app, clusters_above_threshold);
    
    % Generate color map
    num_clusters = length(clusters_above_threshold);
    cmap = feval(app.currentcolormap, num_clusters);  
    cluster_colors = zeros(max(app.Clusters1), 3);
    for k = 1:num_clusters
        if clusters_above_threshold(k) > 0
            cluster_colors(clusters_above_threshold(k), :) = cmap(k, :);
        end
    end
    app.ClusterColors = cluster_colors;
    
    % Update bar plot
    updateBarPlot(app, app.Clusters1, valid_clusters, clusters_above_threshold, cmap);
    
    % Update scatter plot
    updateScatterPlot(app, app.Clusters1, clusters_above_threshold, cmap);
end

function updateScatterPlot(app, idx, clusters_above_threshold, cmap)
    colormap(app.UIAxes2, cmap);

    % Calculate limits based on the full dataset
    maxData = max(app.Data1);
    minData = min(app.Data1);
    padding = 0.1 * (maxData - minData);
    app.XLimit_UIA2 = [minData(1) - padding(1), maxData(1) + padding(1)];
    app.YLimit_UIA2 = [minData(2) - padding(2), maxData(2) + padding(2)];
    app.ZLimit_UIA2 = [minData(3) - padding(3), maxData(3) + padding(3)];

    % Calculate offsets for x and y
    x_offset = minData(1);
    y_offset = minData(2);

    if app.ShowClustersOnlyCheckbox.Value
        non_noise_indices = idx ~= -1 & ismember(idx, clusters_above_threshold);
        app.filtered_data = app.Data1(non_noise_indices, :);
        filtered_idx = idx(non_noise_indices);
    else
        app.filtered_data = app.Data1;
        filtered_idx = idx;
    end
    
    % Adjust the data
    adjusted_data = app.filtered_data;
    adjusted_data(:, 1) = adjusted_data(:, 1) - x_offset;
    adjusted_data(:, 2) = adjusted_data(:, 2) - y_offset;
    
    if app.UseCustomColors
        scatter_color = app.getColor(app.ChannelColors{1});
        scatter_color = repmat(scatter_color, size(adjusted_data, 1), 1);
    else
        scatter_colors = zeros(size(filtered_idx, 1), 3);
        for i = 1:length(clusters_above_threshold)
            scatter_colors(filtered_idx == clusters_above_threshold(i), :) = repmat(cmap(i, :), sum(filtered_idx == clusters_above_threshold(i)), 1);
        end
        scatter_colors(~ismember(filtered_idx, clusters_above_threshold), :) = repmat([0.7, 0.7, 0.7], sum(~ismember(filtered_idx, clusters_above_threshold)), 1);
        scatter_color = scatter_colors;
    end
    
    scatter3(app.UIAxes2, adjusted_data(:, 1), adjusted_data(:, 2), adjusted_data(:, 3), 20, scatter_color, 'filled');
    axis(app.UIAxes2, 'equal');
    
    % Set consistent limits
    xlim(app.UIAxes2, [0, app.XLimit_UIA2(2) - x_offset]);
    ylim(app.UIAxes2, [0, app.YLimit_UIA2(2) - y_offset]);
    zlim(app.UIAxes2, app.ZLimit_UIA2);
    
    xlabel(app.UIAxes2, 'X (nm)', 'FontSize', 16, 'FontWeight','bold');
    ylabel(app.UIAxes2, 'Y (nm)', 'FontSize', 16, 'FontWeight','bold');
    zlabel(app.UIAxes2, 'Z (nm)', 'FontSize', 16, 'FontWeight','bold');
    app.UIAxes2.XAxis.FontSize = 16;
    app.UIAxes2.YAxis.FontSize = 16;
    app.UIAxes2.ZAxis.FontSize = 16;
    title(app.UIAxes2, '', 'FontSize', 12, 'FontWeight', 'bold');
    
    % Set tick marks to start from 0 with 500 nm spacing
    xticks(app.UIAxes2, 0:500:ceil(app.XLimit_UIA2(2) - x_offset));
    yticks(app.UIAxes2, 0:500:ceil(app.YLimit_UIA2(2) - y_offset));
 
    grid(app.UIAxes2, "on");
end


        function updateUIElements(app, clusters_above_threshold)
            cluster_items = cellstr(num2str(clusters_above_threshold));
            app.ListBox.Items = cluster_items;
            app.ListBox_2.Items = cluster_items;

            app.ListBox2.Items = cluster_items;
            app.ListBox2_2.Items = cluster_items;

            if ~isempty(cluster_items)
                app.ListBox.Value = cluster_items{1};
                app.ListBox_2.Value = cluster_items{1};
                app.ListBox2.Value = cluster_items{1};
                app.ListBox2_2.Value = cluster_items{1};
            end
        end

        function updateBarPlot(app, idx, valid_clusters, clusters_above_threshold, cmap)
            counts = histcounts(idx(idx ~= -1), [valid_clusters; max(valid_clusters) + 1]);
            bar_handle = bar(app.UIAxes, valid_clusters, counts, 'FaceColor', 'flat');
            title(app.UIAxes, 'Points per Cluster');

            for k = 1:length(valid_clusters)
                if ismember(valid_clusters(k), clusters_above_threshold)
                    bar_handle.CData(k, :) = cmap(find(clusters_above_threshold == valid_clusters(k), 1), :);
                else
                    bar_handle.CData(k, :) = [0.7, 0.7, 0.7]; % color for excluded clusters
                end
            end
        end


        function [sigmaX, sigmaY, sigmaZ, sigmaR, ste_r, ste_z] = calculateLocalizationPrecision(app, data, tid, tim)
            [~, ~, uid] = unique(tid, 'stable');
            c.n = accumarray(uid, 1);
            N = numel(c.n);
            c.t = accumarray(uid, tim) ./ c.n;
            c.pos = zeros(N, 3);
            c.std_xyz = zeros(N, 3);
            for i = 1:3
                c.pos(:, i) = accumarray(uid, data(:, i)) ./ c.n;
                c.std_xyz(:, i) = accumarray(uid, data(:, i), [], @std);
            end

            % Filter points observed more than once
            s = c.std_xyz(c.n > 1, :);
            n = c.n(c.n > 1);

            % Calculate combined standard deviation for the radial component
            sr = sqrt(s(:, 1).^2 + s(:, 2).^2);

            % Calculate standard error for the radial component
            ste_r = sr ./ sqrt(n);

            % Calculate standard error for the z component
            ste_z = s(:, 3) ./ sqrt(n);

            % Assign output variables
            sigmaX = s(:, 1);
            sigmaY = s(:, 2);
            sigmaZ = s(:, 3);
            sigmaR = sr;

            % Store the results in the app property
            c.ste_rz = [ste_r, ste_z];
            app.Minflux.combined = c;
            app.Minflux.combined.n = n;
        end

        function updateLocalizationPrecisionTab(app)
            % Clear existing axes
            delete(findobj(app.LocalizationPrecisionTab, 'Type', 'UIAxes'));

            % Determine which stainings are loaded
            loadedStainings = {'Data1'};
            if ~isempty(app.Data2), loadedStainings{end+1} = 'Data2'; end
            if ~isempty(app.Data3), loadedStainings{end+1} = 'Data3'; end
            if ~isempty(app.Data4), loadedStainings{end+1} = 'Data4'; end
            if ~isempty(app.Data5), loadedStainings{end+1} = 'Data5'; end

            numStainings = length(loadedStainings);

            % Set up common parameters
            T = 5; % Minimum number of localizations
            g = 0:1:15; % Limit of x-range for histogram
            labels = {'x', 'y', 'z', 'r'};

            % Fixed size for each plot
            plotWidth = 125;
            plotHeight = 125;

            % Spacing between plots
            hSpacing = 5;
            vSpacing = 10;

            % Font sizes
            titleFontSize = 7;
            labelFontSize = 6;
            tickFontSize = 6;

            % Calculate content size
            contentWidth = 4 * (plotWidth + hSpacing) + hSpacing;
            contentHeight = max(numStainings * (plotHeight + vSpacing) + vSpacing + 50, app.LocalizationPrecisionTab.Position(4));

            % Create a uipanel to hold all the plots, with no visible border
            contentPanel = uipanel(app.LocalizationPrecisionTab, ...
                'Position', [1 1 contentWidth contentHeight], ...
                'Units', 'pixels', ...
                'BorderType', 'none', ...
                'BackgroundColor', app.LocalizationPrecisionTab.BackgroundColor);

            % Position the Save PNG button at the top of the content panel
            % Preserve the original size of the button
            originalButtonPosition = app.SaveLocPrecPDFButton.Position;
            app.SaveLocPrecPDFButton.Parent = contentPanel;
            app.SaveLocPrecPDFButton.Position = [20, contentHeight - originalButtonPosition(4) - 10, originalButtonPosition(3), originalButtonPosition(4)];

            % Start position for plots (below the button)
            yStartPosition = contentHeight - originalButtonPosition(4) - 20;

            for i = 1:numStainings
                staining = app.(loadedStainings{i});

                % Get the correct tid and tim for this dataset
                if i == 1
                    tid = app.tid1;
                    tim = app.tim1;
                    labelField = 'FileNameLabel1';
                else
                    tid = app.(['tid', num2str(i)]);
                    tim = app.(['tim', num2str(i)]);
                    %labelField = ['FileNameLabel', num2str(i)];
                end

                % Get the appropriate name for the plot
                labelText = app.(labelField).Text;
                if startsWith(labelText, 'File:')
                    [~, plotName, ~] = fileparts(labelText(7:end));
                elseif contains(labelText, '(') && contains(labelText, ')')
                    plotName = extractBetween(labelText, '(', ')');
                    plotName = plotName{1};
                else
                    plotName = labelText;
                end

                % Calculate localization precision
                [sigmaX, sigmaY, sigmaZ, sigmaR, ~, ~] = app.calculateLocalizationPrecision(staining, tid, tim);

                sigmas = [sigmaX, sigmaY, sigmaZ, sigmaR];
                n = app.Minflux.combined.n;
                validIndices = n >= T;
                validIndices = validIndices(1:size(sigmas,1));
                sigmas = sigmas(validIndices, :);

                med = median(sigmas, 1);

                % Create axes and plot
                for j = 1:4
                    xPos = (j-1) * (plotWidth + hSpacing) + hSpacing;
                    yPos = yStartPosition - i * (plotHeight + vSpacing);

                    ax = uiaxes(contentPanel, 'Position', [xPos, yPos, plotWidth, plotHeight], 'Units', 'pixels');

                    h = histogram(ax, sigmas(:, j), g, 'facecolor', 'none', 'Edgecolor', 'k');
                    hold(ax, 'on');

                    ylim(ax, [0, max(h.Values) * 1.1]);
                    plot(ax, med(j) * [1, 1], ylim(ax), 'LineWidth', 1, 'Color', 'red');
                    xlim(ax, g([1, end]));

                    xlabel(ax, ['\sigma_', labels{j}, ' (nm)'], 'FontSize', labelFontSize);
                    ylabel(ax, 'Counts', 'FontSize', labelFontSize);
                    title(ax, sprintf('\\sigma_{%s} = %.2f nm\n(%s)', labels{j}, med(j), plotName), 'FontSize', titleFontSize);

                    % Set font size for tick labels
                    ax.XAxis.FontSize = tickFontSize;
                    ax.YAxis.FontSize = tickFontSize;

                    hold(ax, 'off');
                end
            end

            % Set the tab's content size to enable scrolling
            app.LocalizationPrecisionTab.UserData = struct('ContentSize', [contentWidth contentHeight]);
        end

        function cleanedData = removeOutliers(app, data)
            meanVal = mean(data);
            stdVal = std(data);
            cleanedData = data(abs(data - meanVal) <= 5 * stdVal);
        end

function plotSelectedClusters(app)
    selectedClusters = str2double(app.ListBox.Value);
    cla(app.UIAxes5, 'reset');
    hold(app.UIAxes5, 'on');
    allCenteredPoints = [];

    for i = 1:numel(selectedClusters)
        clusterIndex = selectedClusters(i);
        clusterPoints = app.Data1(app.Clusters1 == clusterIndex & app.IsNoise1 == 0, :);
        centroid = mean(clusterPoints, 1);
        centeredPoints = bsxfun(@minus, clusterPoints, centroid);
        allCenteredPoints = [allCenteredPoints; centeredPoints];
        if app.UseCustomColors
            color = app.getColor(app.ChannelColors{1});
        else
            color = app.ClusterColors(clusterIndex, :);
        end
        scatter3(app.UIAxes5, centeredPoints(:, 1), centeredPoints(:, 2), centeredPoints(:, 3), 20, color, 'filled', 'MarkerFaceAlpha', 0.3);
    end

    app.allCenteredPoints = allCenteredPoints;

            % Combined Alpha shape visualization for all selected clusters
            if app.AlphaShapeCheckBox.Value && size(allCenteredPoints, 1) >= 4
                alphaRadius = app.AlphaRadiusSlider.Value;
                shp = alphaShape(allCenteredPoints, alphaRadius);
                % If only one cluster is selected, use its color for the alpha shape
                if numel(selectedClusters) == 1
                    alphaShapeColor = app.ClusterColors(selectedClusters, :);
                else
                    alphaShapeColor = 'k';
                end
                plot(shp, 'FaceColor', alphaShapeColor, 'FaceAlpha', 0.1, 'EdgeAlpha', 0.2, 'Parent', app.UIAxes5);
            end

            if app.EllipsoidFitCheckBox.Value && ~isempty(allCenteredPoints)
                X = allCenteredPoints(:, 1);
                Y = allCenteredPoints(:, 2);
                Z = allCenteredPoints(:, 3);
                A = [2*X, 2*Y, 2*Z, ones(size(X))];
                b = X.^2 + Y.^2 + Z.^2;
                lambda = 0.1; % Regularization parameter
                params = (A' * A + lambda * eye(size(A, 2))) \ (A' * b);
                x0 = params(1) / 2;
                y0 = params(2) / 2;
                z0 = params(3) / 2;
                r = sqrt(params(4) + x0^2 + y0^2 + z0^2);
                [x, y, z] = sphere(10);
                x = r * x + x0;
                y = r * y + y0;
                z = r * z + z0;
                surf(app.UIAxes5, x, y, z, 'FaceColor', 'none', 'FaceAlpha', 0.3, 'EdgeColor', 'r', 'LineWidth', 1);
                textPosition = [x0 + r + 0.6*r, y0, z0];
                textStr = sprintf('Radius: %.2f nm', r);
                text(app.UIAxes5, textPosition(1), textPosition(2), textPosition(3), textStr, 'FontSize', 16, 'Color', 'r');
            end

            scatter3(app.UIAxes5, 0, 0, 0, 100, 'red', 'filled');
            % Set axis limits based on the custom box sizes
            xlim(app.UIAxes5, [-app.BoxSizeX/2, app.BoxSizeX/2]);
            ylim(app.UIAxes5, [-app.BoxSizeY/2, app.BoxSizeY/2]);
            zlim(app.UIAxes5, [-app.BoxSizeZ/2, app.BoxSizeZ/2]);

            xlabel(app.UIAxes5, 'X (nm)');
            ylabel(app.UIAxes5, 'Y (nm)');
            zlabel(app.UIAxes5, 'Z (nm)');
            title(app.UIAxes5, sprintf('DBSCAN (single) - Box Size: %dx%dx%d nm', app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ));

            view(app.UIAxes5, 3);
            grid(app.UIAxes5, 'on');
            axis(app.UIAxes5, 'equal');
            rotate3d(app.UIAxes5, 'on');

            % Adjust data aspect ratio to maintain relative scaling
            daspect(app.UIAxes5, [1 1 1]);
        end

        function plotSelectedClusters2(app)
    selectedClusters = str2double(app.ListBox_2.Value);
    cla(app.UIAxes5_2, 'reset');
    hold(app.UIAxes5_2, 'on');

    % Error checking
    if isempty(selectedClusters)
        disp('No clusters selected');
        return;
    end

    % Plot main cluster data
    allClusterPoints = [];
    clusterMask = false(size(app.Clusters1));
    for i = 1:numel(selectedClusters)
        clusterIndex = selectedClusters(i);
        currentClusterMask = app.Clusters1 == clusterIndex & ~app.IsNoise1;
        clusterMask = clusterMask | currentClusterMask;
        clusterPoints = app.Data1(currentClusterMask, :);
        allClusterPoints = [allClusterPoints; clusterPoints];
    end

    % Calculate centroid of all selected clusters
    centroid = mean(allClusterPoints, 1);

    % Center all data around (0,0,0)
    centeredClusterPoints = allClusterPoints - centroid;

    % Plot centered cluster points
    for i = 1:numel(selectedClusters)
        clusterIndex = selectedClusters(i);
        currentClusterMask = app.Clusters1 == clusterIndex & ~app.IsNoise1;
        currentCenteredPoints = app.Data1(currentClusterMask, :) - centroid;
        if app.UseCustomColors
            color = app.getColor(app.ChannelColors{1});
        else
            color = app.ClusterColors(clusterIndex, :);
        end
        scatter3(app.UIAxes5_2, currentCenteredPoints(:, 1), currentCenteredPoints(:, 2), currentCenteredPoints(:, 3), 20, color, 'filled', 'MarkerFaceAlpha', 0.3);
    end            

    % Alpha shape visualization
    if app.AlphaShapeCheckBox_2.Value && size(centeredClusterPoints, 1) >= 4
        alphaRadius = app.AlphaRadiusSlider_2.Value;
        shp = alphaShape(centeredClusterPoints, alphaRadius);
        if numel(selectedClusters) == 1
            alphaShapeColor = app.ClusterColors(selectedClusters, :);
        else
            alphaShapeColor = 'k';
        end
        plot(shp, 'FaceColor', alphaShapeColor, 'FaceAlpha', 0.1, 'EdgeAlpha', 0.2, 'Parent', app.UIAxes5_2);
    end

    % Ellipsoid fit
            if app.EllipsoidFitCheckBox.Value && ~isempty(allCenteredPoints)
                X = allCenteredPoints(:, 1);
                Y = allCenteredPoints(:, 2);
                Z = allCenteredPoints(:, 3);
                A = [2*X, 2*Y, 2*Z, ones(size(X))];
                b = X.^2 + Y.^2 + Z.^2;
                lambda = 0.1; % Regularization parameter
                params = (A' * A + lambda * eye(size(A, 2))) \ (A' * b);
                x0 = params(1) / 2;
                y0 = params(2) / 2;
                z0 = params(3) / 2;
                r = sqrt(params(4) + x0^2 + y0^2 + z0^2);
                [x, y, z] = sphere(10);
                x = r * x + x0;
                y = r * y + y0;
                z = r * z + z0;
                surf(app.UIAxes5, x, y, z, 'FaceColor', 'none', 'FaceAlpha', 0.3, 'EdgeColor', 'r', 'LineWidth', 1);
                textPosition = [x0 + r + 0.6*r, y0, z0];
                textStr = sprintf('Radius: %.2f nm', r);
                text(app.UIAxes5, textPosition(1), textPosition(2), textPosition(3), textStr, 'FontSize', 16, 'Color', 'r');
            end

    % Plot additional channels
    channelData = {app.Data2_off, app.Data3_off, app.Data4_off, app.Data5_off};
    checkBoxes = {app.Ch2CheckBox, app.Ch3CheckBox, app.Ch4CheckBox, app.Ch5CheckBox};
    colors = {'cyan', 'red', 'magenta', 'green'};

    for i = 1:4
        if checkBoxes{i}.Value && ~isempty(channelData{i})
            data_nm = channelData{i};
            centered_data = data_nm - centroid;
            in_bbox = all(abs(centered_data) <= [app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ]/2, 2);
            scatter3(app.UIAxes5_2, centered_data(in_bbox, 1), centered_data(in_bbox, 2), centered_data(in_bbox, 3), 20, colors{i}, 'filled', 'MarkerFaceAlpha', 0.7);
        end
    end

    % Set axis properties and limits
    xlim(app.UIAxes5_2, [-app.BoxSizeX/2, app.BoxSizeX/2]);
    ylim(app.UIAxes5_2, [-app.BoxSizeY/2, app.BoxSizeY/2]);
    zlim(app.UIAxes5_2, [-app.BoxSizeZ/2, app.BoxSizeZ/2]);

    xlabel(app.UIAxes5_2, 'X (nm)');
    ylabel(app.UIAxes5_2, 'Y (nm)');
    zlabel(app.UIAxes5_2, 'Z (nm)');
    title(app.UIAxes5_2, sprintf('DBSCAN (multi) - Box Size: %dx%dx%d nm', app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ));

    view(app.UIAxes5_2, 3);
    grid(app.UIAxes5_2, 'on');
    axis(app.UIAxes5_2, 'equal');
    rotate3d(app.UIAxes5_2, 'on');

    % Adjust data aspect ratio to maintain relative scaling
    daspect(app.UIAxes5_2, [1 1 1]);

    hold(app.UIAxes5_2, 'off');
end

        function [trace_data, trace_cluster_assignments] = calculateTracesForChannel(app, data, tid, clusters, isNoise)
            if isempty(data) || isempty(tid) || isempty(clusters) || isempty(isNoise)
                trace_data = [];
                trace_cluster_assignments = [];
                return;
            end

            % Ensure all arrays have the same length
            minLength = min([size(data,1), length(tid), length(clusters), length(isNoise)]);
            validIndices = ~isNoise(1:minLength);
            validData = data(1:minLength, :);
            validTid = tid(1:minLength);
            validClusters = clusters(1:minLength);

            [uniqueTids, ~, idx] = unique(validTid);
            trace_data = zeros(length(uniqueTids), size(validData, 2));
            trace_cluster_assignments = zeros(length(uniqueTids), 1);

            for i = 1:length(uniqueTids)
                traceIndices = validTid == uniqueTids(i);
                trace_data(i, :) = mean(validData(traceIndices, :), 1);
                trace_cluster_assignments(i) = mode(validClusters(traceIndices));
            end
        end

     function calculateTraceData(app)
    for i = 1:5
        dataField = ['Data', num2str(i)];
        if i > 1
            dataField = [dataField, '_off'];
        end
        tidField = ['tid', num2str(i)];
        clusterField = ['Clusters', num2str(i)];
        isNoiseField = ['IsNoise', num2str(i)];
        traceDataField = ['trace_data', num2str(i)];
        traceClusterField = ['trace_clusters', num2str(i)];
        
        if ~isempty(app.(dataField)) && ~isempty(app.(tidField))
            [app.(traceDataField), app.(traceClusterField)] = app.calculateTracesForChannel(app.(dataField), app.(tidField), app.(clusterField), app.(isNoiseField));
        else
            app.(traceDataField) = [];
            app.(traceClusterField) = [];
            warning(['Channel ', num2str(i), ' data or tid is empty']);
        end
    end
    
    % Print summary
    disp('Trace data calculation complete:');
    for i = 1:5
        traceDataField = ['trace_data', num2str(i)];
        disp(['Channel ', num2str(i), ': ', num2str(size(app.(traceDataField), 1)), ' traces']);
    end
end


        function Measurement(app)
            selectedClusters = str2double(app.ListBox2.Value);

            cla(app.UIAxes6, 'reset');
            hold(app.UIAxes6, 'on');

            num_bins = 5;
            bin_edges = 0:5:120;

            allDistances = [];
            allCounts = [];
            allCounts2 = []; % To collect all second channel counts
            allCounts3 = []; % To collect all third channel counts
            allCounts4 = []; % To collect all fourth channel counts
            allCounts5 = []; % To collect all fifth channel counts

            maxCountOverall = -inf;

            % Ensure trace data is calculated
    app.calculateTraceData();
    
    % Determine which data to use based on the ButtonGroup1 selection
    if app.trcButton1.Value
        mainData = app.trace_data1;
        data2 = app.trace_data2;
        data3 = app.trace_data3;
        data4 = app.trace_data4;
        data5 = app.trace_data5;
        clusterAssignments = app.trace_clusters1;
        isNoise = clusterAssignments == 0;  % Assuming 0 represents noise in trace clusters
    else
        mainData = app.Data1;
        data2 = app.Data2_off;
        data3 = app.Data3_off;
        data4 = app.Data4_off;
        data5 = app.Data5_off;
        clusterAssignments = app.Clusters1;
        isNoise = app.IsNoise1;
    end

            % First loop to find the overall maximum count
            for i = 1:numel(selectedClusters)
                clusterIndex = selectedClusters(i);
                clusterPoints = mainData(clusterAssignments == clusterIndex & ~isNoise, :);

                centroid = mean(clusterPoints, 1);
                centeredPoints = clusterPoints - centroid;

                distances = sqrt(sum(centeredPoints.^2, 2));
                counts = histcounts(distances, bin_edges);

                currentMaxCount = max(counts);
                if currentMaxCount > maxCountOverall
                    maxCountOverall = currentMaxCount;
                end
            end

            % Second loop to plot the data using the overall maximum count for normalization
            for i = 1:numel(selectedClusters)
                clusterIndex = selectedClusters(i);
                clusterPoints = mainData(clusterAssignments == clusterIndex & ~isNoise, :);

                centroid = mean(clusterPoints, 1);
                centeredPoints = clusterPoints - centroid;

                color = app.ClusterColors(clusterIndex, :);

                distances = sqrt(sum(centeredPoints.^2, 2));
                allDistances = [allDistances; distances];

                counts = histcounts(distances, bin_edges);
                allCounts(i, :) = counts;

                presence_count = histcounts(distances, bin_edges);
                presence_count_norm = presence_count / maxCountOverall * 100;

                b = bar(app.UIAxes6, bin_edges(1:end-1), presence_count_norm, 'histc');
                b.FaceColor = color;  % Set the color of the bar
                b.DisplayName = ['Cluster ', num2str(clusterIndex)];

                % Collect second channel data
                if ~isempty(data2)
                    distances2 = sqrt(sum((data2 - centroid).^2, 2));
                    vicinity_threshold = 500; % Define the vicinity threshold
                    vicinity_indices = distances2 <= vicinity_threshold;
                    distances2_vicinity = distances2(vicinity_indices);

                    if ~isempty(distances2_vicinity)
                        counts2 = histcounts(distances2_vicinity, bin_edges);
                        allCounts2 = [allCounts2; counts2];
                    end
                end

                % Collect third channel data
                if ~isempty(data3)
                    distances3 = sqrt(sum((data3 - centroid).^2, 2));
                    vicinity_threshold = 500; % Define the vicinity threshold
                    vicinity_indices = distances3 <= vicinity_threshold;
                    distances3_vicinity = distances3(vicinity_indices);

                    if ~isempty(distances3_vicinity)
                        counts3 = histcounts(distances3_vicinity, bin_edges);
                        allCounts3 = [allCounts3; counts3];
                    end
                end

                % Collect fourth channel data
                if ~isempty(data4)
                    distances4 = sqrt(sum((data4 - centroid).^2, 2));
                    vicinity_threshold = 500; % Define the vicinity threshold
                    vicinity_indices = distances4 <= vicinity_threshold;
                    distances4_vicinity = distances4(vicinity_indices);

                    if ~isempty(distances4_vicinity)
                        counts4 = histcounts(distances4_vicinity, bin_edges);
                        allCounts4 = [allCounts4; counts4];
                    end
                end

                % Collect fifth channel data
                if ~isempty(data5)
                    distances5 = sqrt(sum((data5 - centroid).^2, 2));
                    vicinity_threshold = 500; % Define the vicinity threshold
                    vicinity_indices = distances5 <= vicinity_threshold;
                    distances5_vicinity = distances5(vicinity_indices);

                    if ~isempty(distances5_vicinity)
                        counts5 = histcounts(distances5_vicinity, bin_edges);
                        allCounts5 = [allCounts5; counts5];
                    end
                end
            end

            if numel(selectedClusters) >= 2
                % Calculate mean and standard error of all distances
                meanDistance = mean(allDistances);
                standardError = std(allDistances) / sqrt(length(allDistances));
                app.MeanDistLabel.Text = sprintf('Mean Distance: %.2f ± %.2f', meanDistance, standardError);

                % Plot the mean line
                yLimits = ylim(app.UIAxes6);
                plot(app.UIAxes6, [meanDistance, meanDistance], yLimits*1.2, 'r--', 'LineWidth', 2, 'DisplayName', 'Mean Distance');

                % Calculate and plot mean and standard error for multiple clusters
                meanCounts = mean(allCounts, 1);
                seCounts = std(allCounts, 0, 1) / sqrt(size(allCounts, 1));
                maxCount = max(meanCounts);
                meanCountsNorm = meanCounts / maxCountOverall * 100;
                seCountsNorm = seCounts / maxCountOverall * 100;

                binCenters = (bin_edges(1:end-1) + bin_edges(2:end)) / 2;
                fitresult = fit(binCenters', meanCountsNorm', 'smoothingspline');

                yyaxis(app.UIAxes6, 'left');
                plot(app.UIAxes6, binCenters, fitresult(binCenters), 'k-', 'LineWidth', 2, 'DisplayName', 'Mean Counts(norm.)');
                fill(app.UIAxes6, [binCenters, fliplr(binCenters)], [fitresult(binCenters)' + seCountsNorm, fliplr(fitresult(binCenters)' - seCountsNorm)], 'k', 'FaceAlpha', 0.2, 'EdgeColor', 'none');

            elseif isscalar(selectedClusters)
                % Calculate the distance of maximal counts
                counts = histcounts(allDistances, bin_edges);
                [~, maxCountIndex] = max(counts);
                distanceOfMaxCounts = bin_edges(maxCountIndex);

                app.MeanDistLabel.Text = sprintf('Distance of Max Counts: %.2f', distanceOfMaxCounts);
            end

            % Protein localization plotting
            channelData = {allCounts2, allCounts3, allCounts4, allCounts5};
            channelColors = {'c', 'r', 'm', 'b'};
            channelNames = {app.filename2, app.filename3, app.filename4, app.filename5};

            yyaxis(app.UIAxes6, 'right');
            hold(app.UIAxes6, 'on');

            % Find the maximum count across all channels for global normalization
            maxOverallCount = 0;
            if ~app.NormalizeIndividuallyCheckBox.Value
                for j = 1:length(channelData)
                    if ~isempty(channelData{j})
                        meanCounts = mean(channelData{j}, 1);
                        validMeanCounts = meanCounts(~isnan(meanCounts));
                        maxOverallCount = max(maxOverallCount, max(validMeanCounts));
                    end
                end
            end

            for j = 1:length(channelData)
                if ~isempty(channelData{j})
                    meanCounts = mean(channelData{j}, 1);
                    seCounts = std(channelData{j}, 0, 1) / sqrt(size(channelData{j}, 1));

                    binCenters = (bin_edges(1:end-1) + bin_edges(2:end)) / 2;

                    % Remove NaN values
                    validIndices = ~isnan(meanCounts) & ~isnan(binCenters);
                    validMeanCounts = meanCounts(validIndices);
                    validBinCenters = binCenters(validIndices);
                    validSECounts = seCounts(validIndices);

                    if length(validMeanCounts) > 3  % Ensure we have enough points for plotting
                        % Determine normalization factor based on checkbox
                        if app.NormalizeIndividuallyCheckBox.Value
                            normFactor = max(validMeanCounts);
                        else
                            normFactor = maxOverallCount;
                        end

                        if normFactor > 0  % Avoid division by zero
                            normalizedMeanCounts = validMeanCounts / normFactor * 100;
                            normalizedSECounts = validSECounts / normFactor * 100;

                            % Plot the normalized data
                            plot(app.UIAxes6, validBinCenters, normalizedMeanCounts, [channelColors{j}, '-'], 'LineWidth', 2, 'DisplayName', [channelNames{j}, ' Channel']);

                            % Plot the standard error as a shaded area
                            upperBound = min(normalizedMeanCounts + normalizedSECounts, 100);
                            lowerBound = max(normalizedMeanCounts - normalizedSECounts, 0);
                            fill(app.UIAxes6, [validBinCenters, fliplr(validBinCenters)], ...
                                [upperBound, fliplr(lowerBound)], ...
                                channelColors{j}, 'FaceAlpha', 0.2, 'EdgeColor', 'none', 'DisplayName', [channelNames{j}, ' Channel SE']);
                        else
                            warning('All zero counts for channel %s. Skipping this channel.', channelNames{j});
                        end
                    else
                        warning('Not enough valid data points for channel %s. Skipping this channel.', channelNames{j});
                    end
                end
            end

            % Set the y-axis limits for the right axis (protein localization)
            yyaxis(app.UIAxes6, 'right');
            ylim(app.UIAxes6, [0, 100]);  % Set to percentage scale
            if app.NormalizeIndividuallyCheckBox.Value
                ylabel(app.UIAxes6, 'Normalized Protein Localization (% of channel max)');
            else
                ylabel(app.UIAxes6, 'Normalized Protein Localization (% of overall max)');
            end

            % Switch back to left axis for main data
            yyaxis(app.UIAxes6, 'left');
            ylabel(app.UIAxes6, 'Mean Counts');

            % Adjust y-axis limits on the left
            yLimitsLeft = ylim(app.UIAxes6);
            ylim(app.UIAxes6, [0, yLimitsLeft(2) * 1.1]);

            % Set x-axis limits
            xlim(app.UIAxes6, [0, 120]);

            % Final plot adjustments
            hold(app.UIAxes6, 'off');
            xlabel(app.UIAxes6, 'Distance from Centroid (units)');
            title(app.UIAxes6, 'Distance of Localizations to Centroid');
            legend(app.UIAxes6, 'show', 'Location', 'eastoutside');
            grid(app.UIAxes6, 'on');
        end

        function updatePlots2(app)
            cla(app.UIAxes7);
            hold(app.UIAxes7, 'on');  % Set hold on at the beginning
            legendEntries = {};

            for i = 1:length(app.ChannelListBox.Items)
                displayChannelName = app.ChannelListBox.Items{i};

                % Skip the 'New Channel' item
                if strcmp(displayChannelName, 'New Channel')
                    continue;
                end

                channelNumber = str2double(regexp(displayChannelName, '\d+', 'match'));
                channelName = ['Channel_', num2str(channelNumber)];

                % Check if the channel exists and is enabled
                if isfield(app.ChannelEnableStates, channelName) && app.ChannelEnableStates.(channelName)
                    data = app.(['Data', num2str(channelNumber)]);


                    if ~isempty(data)
                        if app.DBSCANCheckBox.Value
                            idx = applyDBSCAN(app, data, channelNumber);

                            % Apply size exclusion
                            if app.EnableExclusion.Value
                                exclusionThreshold = app.ExclNum.Value;
                                validClusters = unique(idx(idx > 0));
                                validClusters = validClusters(arrayfun(@(x) sum(idx == x), validClusters) >= exclusionThreshold);
                                isValid = ismember(idx, validClusters);
                            else
                                isValid = idx > 0;
                            end

                            data = data(isValid, :);
                        end

                        % Apply stored corrections
                        if isfield(app.ChannelCorrections, channelName)
                            corrections = app.ChannelCorrections.(channelName);
                            data(:,1) = data(:,1) + corrections.X;
                            data(:,2) = data(:,2) + corrections.Y;
                            data(:,3) = data(:,3) + corrections.Z;
                        end

                        app.(['Data', num2str(channelNumber), '_off']) = data;

                        % Use updateScatterMulti
                        if ~isempty(data)
                            if app.UseCustomColors
                                color = app.ChannelColors{channelNumber};
                            else
                                color = app.getChannelColor(channelNumber);
                            end
                            updateScatterMulti(app, app.UIAxes7, data, color);
                            legendEntries{end+1} = displayChannelName;
                        end
                    end
                end
            end
            updateCheckboxVisibility(app);
            % Set axis properties after all channels are plotted
            title(app.UIAxes7, 'Multicolor MINFLUX Data', 'FontSize', 16, 'FontWeight', 'bold');
            xlabel(app.UIAxes7, 'X (nm)', 'FontSize', 16, 'FontWeight', 'bold');
            ylabel(app.UIAxes7, 'Y (nm)', 'FontSize', 16, 'FontWeight', 'bold');
            zlabel(app.UIAxes7, 'Z (nm)', 'FontSize', 16, 'FontWeight', 'bold');
            axis(app.UIAxes7, 'equal');
            view(app.UIAxes7, 3);
            grid(app.UIAxes7, 'on');

            app.UIAxes7.XAxis.FontSize = 16;
            app.UIAxes7.YAxis.FontSize = 16;
            app.UIAxes7.ZAxis.FontSize = 16;

            % After all channels are plotted, set the final axis limits
            xlim(app.UIAxes7, [0, app.GlobalXLim(2) - app.GlobalXLim(1)]);
            ylim(app.UIAxes7, [0, app.GlobalYLim(2) - app.GlobalYLim(1)]);
            zlim(app.UIAxes7, app.GlobalZLim);

            xticks(app.UIAxes7, app.GlobalXTicks - app.GlobalXLim(1));
            yticks(app.UIAxes7, app.GlobalYTicks - app.GlobalYLim(1));
            zticks(app.UIAxes7, app.GlobalZTicks);

            legend(app.UIAxes7, legendEntries, 'Location', 'northeast');

            rotate3d(app.UIAxes7, 'on');

            hold(app.UIAxes7, 'off');  % Turn hold off at the end
        end
        function updateCheckboxVisibility(app)
    checkboxes = {app.Ch2CheckBox, app.Ch3CheckBox, app.Ch4CheckBox, app.Ch5CheckBox};

    for i = 1:4
        channelNumber = i + 1;  % This maps to channels 2, 3, 4, 5
        channelName = ['Channel_', num2str(channelNumber)];
        
        if isfield(app.ChannelFileNames, channelName) && ~isempty(app.ChannelFileNames.(channelName))
            checkboxes{i}.Visible = 'on';
        else
            checkboxes{i}.Visible = 'off';
        end
    end
end

        function updateScatterMulti(app, ax, data, color)
            % Function to round up to the nearest multiple of 100
            roundUpTo100 = @(x) ceil(x / 100) * 100;
            roundDownTo100 = @(x) floor(x / 100) * 100;

            % Calculate limits based on the full dataset
            maxData = max(app.Data1);
            minData = min(app.Data1);

            % Add padding (5% of the range or at least 100 nm)
            padding = max(roundUpTo100(0.05 * (maxData - minData)), 100);

            % Calculate padded and rounded limits
            XLimit = [roundDownTo100(minData(1)) - padding(1), roundUpTo100(maxData(1)) + padding(1)];
            YLimit = [roundDownTo100(minData(2)) - padding(2), roundUpTo100(maxData(2)) + padding(2)];
            ZLimit = [roundDownTo100(minData(3)) - padding(3), roundUpTo100(maxData(3)) + padding(3)];

            % Calculate offsets for x and y to start from 0
            x_offset = XLimit(1);
            y_offset = YLimit(1);

            % Adjust the data
            adjusted_data = data;
            adjusted_data(:, 1) = adjusted_data(:, 1) - x_offset;
            adjusted_data(:, 2) = adjusted_data(:, 2) - y_offset;

            % Check if scatter plot already exists for this color
            existingScatter = findobj(ax, 'Type', 'scatter', 'CData', color);

            if isempty(existingScatter)
                % Create new scatter plot
                scatter3(ax, adjusted_data(:, 1), adjusted_data(:, 2), adjusted_data(:, 3), 20, color, 'filled');
            else
                % Update existing scatter plot
                set(existingScatter, 'XData', adjusted_data(:, 1), 'YData', adjusted_data(:, 2), 'ZData', adjusted_data(:, 3));
                % Update color data
                set(existingScatter, 'CData', repmat(color, size(adjusted_data, 1), 1));
            end

            % Update axis limits
            currentXLim = xlim(ax);
            currentYLim = ylim(ax);
            currentZLim = zlim(ax);

            newXLim = [0, max(currentXLim(2), XLimit(2) - x_offset)];
            newYLim = [0, max(currentYLim(2), YLimit(2) - y_offset)];
            newZLim = [min(currentZLim(1), ZLimit(1)), max(currentZLim(2), ZLimit(2))];

            % Ensure minimum size of 1000x1000x1000 nm
            newXLim(2) = max(newXLim(2), 1000);
            newYLim(2) = max(newYLim(2), 1000);
            newZLim = [min(newZLim(1), -500), max(newZLim(2), 500)];

            % Round limits to nice values
            newXLim(2) = roundUpTo100(newXLim(2));
            newYLim(2) = roundUpTo100(newYLim(2));
            newZLim(1) = roundDownTo100(newZLim(1));
            newZLim(2) = roundUpTo100(newZLim(2));

            xlim(ax, newXLim);
            ylim(ax, newYLim);
            zlim(ax, newZLim);

            % Set tick marks with 500 nm spacing
            xticks(ax, 0:500:newXLim(2));
            yticks(ax, 0:500:newYLim(2));
            zticks(ax, newZLim(1):500:newZLim(2));

            % Update app properties for global limits
            app.GlobalXLim = [x_offset, newXLim(2) + x_offset];
            app.GlobalYLim = [y_offset, newYLim(2) + y_offset];
            app.GlobalZLim = newZLim;
            app.GlobalXTicks = get(ax, 'XTick') + x_offset;
            app.GlobalYTicks = get(ax, 'YTick') + y_offset;
            app.GlobalZTicks = get(ax, 'ZTick');
        end

        function color = getChannelColor(app, channelNumber)
            if channelNumber >= 1 && channelNumber <= length(app.ChannelColors)
                colorName = app.ChannelColors{channelNumber};
                % Convert color name to RGB
                switch lower(colorName)
                    case 'k'
                        color = [0, 0, 0];
                    case 'cyan'
                        color = [0, 1, 1];
                    case 'red'
                        color = [1, 0, 0];
                    case 'magenta'
                        color = [1, 0, 1];
                    case 'green'
                        color = [0, 1, 0];
                    otherwise
                        color = [0, 0, 1]; % Default to blue
                end
            else
                % Default color if channelNumber is out of range
                color = [0, 0, 1]; % Blue
            end
        end
        function rgb = getColor(app, colorInput)
    if isnumeric(colorInput) && numel(colorInput) == 3
        % If it's already an RGB triplet, return it
        rgb = colorInput;
    elseif ischar(colorInput) || isstring(colorInput)
        % Convert to char if it's a string
        colorInput = char(lower(colorInput));
        
        % Define a structure with color names and their RGB values
        colors = struct('red', [1 0 0], 'green', [0 1 0], 'blue', [0 0 1], ...
                        'cyan', [0 1 1], 'magenta', [1 0 1], 'yellow', [1 1 0], ...
                        'black', [0 0 0], 'white', [1 1 1], ...
                        'r', [1 0 0], 'g', [0 1 0], 'b', [0 0 1], ...
                        'c', [0 1 1], 'm', [1 0 1], 'y', [1 1 0], ...
                        'k', [0 0 0], 'w', [1 1 1]);
        
        % Check if the color name exists in our structure
        if isfield(colors, colorInput)
            rgb = colors.(colorInput);
        else
            warning('Invalid color name "%s". Defaulting to blue.', colorInput);
            rgb = [0 0 1];
        end
    else
        warning('Invalid color input. Defaulting to blue.');
        rgb = [0 0 1];
    end
end
        function dataOut = applyOffset(app, dataIn, channelNum)
            xField = ['X', num2str(channelNum), 'Field'];
            yField = ['Y', num2str(channelNum), 'Field'];
            zField = ['Z', num2str(channelNum), 'Field'];

            xOffset = app.(xField).Value;
            yOffset = app.(yField).Value;
            zOffset = app.(zField).Value;

            dataOut = dataIn;
            dataOut(:,1) = dataOut(:,1) + xOffset;
            dataOut(:,2) = dataOut(:,2) + yOffset;
            dataOut(:,3) = dataOut(:,3) + zOffset;
        end


        function [clusterVolumes, clusterSurfaces] = calculateClusterVolumes(app)
            % Determine valid clusters
            validClusters = unique(app.Clusters(app.Clusters ~= 0));

            % Apply exclusion threshold if enabled
            if app.EnableExclusion.Value
                exclusionThreshold = app.ExclNum.Value;
                validClusters = validClusters(arrayfun(@(x) sum(app.Clusters == x), validClusters) >= exclusionThreshold);
            end

            numValidClusters = numel(validClusters);
            clusterVolumes = zeros(numValidClusters, 1);
            clusterSurfaces = zeros(numValidClusters, 1);

            for i = 1:numValidClusters
                clusterIndex = validClusters(i);
                clusterPoints = app.Data(app.Clusters == clusterIndex & app.IsNoise == 0, :);

                if size(clusterPoints, 1) > 3
                    [K, volume] = convhull(clusterPoints(:, 1), clusterPoints(:, 2), clusterPoints(:, 3));
                    clusterVolumes(i) = volume;
                    clusterSurfaces(i) = calculateSurfaceArea(app, clusterPoints, K);
                else
                    clusterVolumes(i) = NaN;
                    clusterSurfaces(i) = NaN;
                end
            end

            % Store the computed volumes and surfaces in the app properties
            app.ClusterVolumes = clusterVolumes;
            app.ClusterSurfaces = clusterSurfaces;

            % Update the UI to display cluster volumes
            volumeStrings = arrayfun(@(x, y) sprintf('Cluster %d: %.5f', x, y), validClusters, clusterVolumes, 'UniformOutput', false);
            app.Volumeinnm3ListBox.Items = volumeStrings;
        end


        function plot3DHeatMap(app, data)
            % Ensure data is not empty and is formatted correctly
            if isempty(data)
                uialert(app.DBSCAN, 'No data to display.', 'Data Error');
                return;
            end

            % Define the grid dimensions and prepare the 3D matrix
            gridSize = 100;  % Higher grid size for better resolution
            V = zeros(gridSize, gridSize, gridSize);

            % Normalize and scale the data points to fit within the grid
            minData = min(data, [], 1);
            maxData = max(data, [], 1);
            dataScaled = bsxfun(@minus, data, minData);
            dataScaled = bsxfun(@rdivide, dataScaled, maxData - minData);
            dataScaled = bsxfun(@times, dataScaled, gridSize - 1) + 1;

            % Voxel accumulation
            indices = floor(dataScaled);
            indices(indices < 1) = 1;
            indices(indices > gridSize) = gridSize;
            for idx = 1:size(data, 1)
                linearIdx = sub2ind(size(V), indices(idx, 1), indices(idx, 2), indices(idx, 3));
                V(linearIdx) = V(linearIdx) + 1;
            end
            % Apply Gaussian smoothing
            V = imgaussfilt3(V, 2);  % Apply Gaussian smoothing with sigma = 2

            % Create a new figure for 3D heatmap if it doesn't exist, or bring it to front if it does
            if isempty(app.figureHandle3D) || ~isvalid(app.figureHandle3D)
                app.figureHandle3D = figure('Name', '3D Heat Map');
            else
                figure(app.figureHandle3D);
            end

            % Visualize with volshow
            h = volshow(V, 'Colormap', hot(256));

            % Adjust alpha map for opacity
            gradient = linspace(0, 1, 256);  % Create a linear gradient
            h.Alphamap = gradient;  % Use a quadratic gradient for smoother transparency

            title('Enhanced 3D Heat Map Visualization');

            % Background color set on the figure
            app.figureHandle3D.Color = 'w';
            h.RenderingStyle = 'VolumeRendering';

            % Call plotHeatMap to create/update 2D projection
            plotHeatMap(app);
        end

        function plotHeatMap(app)
            if isempty(app.allCenteredPoints)
                uialert(app.DBSCAN, 'No data to display.', 'Data Error');
                return;
            end

            xyProjection = app.allCenteredPoints(:, 1:2);
            [counts, centers] = hist3(xyProjection, 'Edges', ...
                {linspace(min(xyProjection(:,1)), max(xyProjection(:,1)), 50), ...
                linspace(min(xyProjection(:,2)), max(xyProjection(:,2)), 50)});

            % Create a new figure for 2D projection if it doesn't exist, or bring it to front if it does
            if isempty(app.figureHandle2D) || ~isvalid(app.figureHandle2D)
                app.figureHandle2D = figure('Name', '2D Heat Map Projection');
            else
                figure(app.figureHandle2D);
            end

            % Clear the current axes and plot the new data
            clf(app.figureHandle2D);
            ax = axes('Parent', app.figureHandle2D);
            imagesc(ax, centers{1}, centers{2}, counts');
            colormap(ax, 'hot');
            colorbar('peer', ax);
            title(ax, 'Heat Map of XY Projection');
            xlabel(ax, 'X Coordinate');
            ylabel(ax, 'Y Coordinate');
            axis(ax, 'equal');
        end


        function PolarMeasurement(app, ~)
            selectedClusters = str2double(app.ListBox2_2.Value);

            delete(app.Panel.Children);

            pax = polaraxes(app.Panel);
            hold(pax, 'on'); % Hold the axes to plot multiple clusters

            rlim(pax, [0 150]);

            allDistances = [];
            allAzimuths = [];

            % Determine which data to use based on the ButtonGroup selection
            if app.trcButton.Value
                app.calculateTraceData();  % Calculate trace data if not already done
                mainData = app.trace_data1;
                data2 = app.trace_data2;
                data3 = app.trace_data3;
                data4 = app.trace_data4;
                data5 = app.trace_data5;
                clusterAssignments = app.trace_clusters1;
                isNoise = clusterAssignments == 0;  % Assuming 0 represents noise in trace clusters
            else
                mainData = app.Data1;
                data2 = app.Data2_off;
                data3 = app.Data3_off;
                data4 = app.Data4_off;
                data5 = app.Data5_off;
                clusterAssignments = app.Clusters1;
                isNoise = app.IsNoise1;
            end

            for i = 1:numel(selectedClusters)
                clusterIndex = selectedClusters(i);
                clusterPoints = mainData(clusterAssignments == clusterIndex & ~isNoise, :);

                centroid = mean(clusterPoints, 1);
                centeredPoints = clusterPoints - centroid;

                if app.ColorCheck.Value
                    color = [0.3, 0.3, 0.3];
                    color2 = [0, 0.4470, 0.7410];
                    color3 = [0.9020, 0.6235, 0];
                    color4 = [0, 0.6196, 0.4510];
                    color5 = [0.8000, 0.4745, 0.6549];
                else
                    color = app.ClusterColors(clusterIndex, :);
                    color2 = 'cyan';
                    color3 = 'red';
                    color4 = 'magenta';
                    color5 = 'green';
                end

                distances = sqrt(sum(centeredPoints.^2, 2));
                azimuth = atan2(centeredPoints(:,2), centeredPoints(:,1));  % Azimuth angle in radians

                allDistances = [allDistances; distances];

                % Plot the polar scatter for the first channel
                plotline = polarscatter(pax, azimuth, distances, 'o', 'MarkerEdgeColor', color, 'DisplayName', ['Cluster ', num2str(clusterIndex)]);
                plotline.LineWidth = 1;

                % Set the radial axis labels to nanometers
                rticks(pax, 0:50:200); % Adjust as needed
                rticklabels(pax, cellstr(num2str((0:50:200)', '%d')));

                % Optionally, set the theta axis labels to degrees
                thetaticks(pax, 0:45:315);
                thetaticklabels(pax, {'0°', '45°', '90°', '135°', '180°', '225°', '270°', '315°'});

                meanDistance = mean(allDistances);
                standardError = std(allDistances) / sqrt(length(allDistances));
                app.MeanDistLabel2.Text = sprintf('Mean Distance: %.2f ± %.2f', meanDistance, standardError);

                % Collect and plot second channel data if available
                if ~isempty(data2)
                    distances2 = sqrt(sum((data2 - centroid).^2, 2));
                    azimuth2 = atan2(data2(:,2) - centroid(2), data2(:,1) - centroid(1));
                    vicinity_threshold = 500; % Define the vicinity threshold
                    vicinity_indices = distances2 <= vicinity_threshold;

                    distances2_vicinity = distances2(vicinity_indices);
                    azimuth2_vicinity = azimuth2(vicinity_indices);

                    % Plot the polar scatter for the second channel
                    plotline2 = polarscatter(pax, azimuth2_vicinity, distances2_vicinity, 'o', 'MarkerEdgeColor', color2, 'DisplayName', app.filename2);
                    plotline2.LineWidth = 1;
                end

                % Collect and plot third channel data if available
                if ~isempty(data3)
                    distances3 = sqrt(sum((data3 - centroid).^2, 2));
                    azimuth3 = atan2(data3(:,2) - centroid(2), data3(:,1) - centroid(1));
                    vicinity_threshold = 500; % Define the vicinity threshold
                    vicinity_indices = distances3 <= vicinity_threshold;

                    distances3_vicinity = distances3(vicinity_indices);
                    azimuth3_vicinity = azimuth3(vicinity_indices);

                    % Plot the polar scatter for the third channel
                    plotline3 = polarscatter(pax, azimuth3_vicinity, distances3_vicinity, 'o', 'MarkerEdgeColor', color3, 'DisplayName', app.filename3);
                    plotline3.LineWidth = 1;
                end

                % Collect and plot fourth channel data if available
                if ~isempty(data4)
                    distances4 = sqrt(sum((data4 - centroid).^2, 2));
                    azimuth4 = atan2(data4(:,2) - centroid(2), data4(:,1) - centroid(1));
                    vicinity_threshold = 500; % Define the vicinity threshold
                    vicinity_indices = distances4 <= vicinity_threshold;

                    distances4_vicinity = distances4(vicinity_indices);
                    azimuth4_vicinity = azimuth4(vicinity_indices);

                    % Plot the polar scatter for the fourth channel
                    plotline4 = polarscatter(pax, azimuth4_vicinity, distances4_vicinity, 'o', 'MarkerEdgeColor', color4, 'DisplayName', app.filename4);
                    plotline4.LineWidth = 1;
                end

                % Collect and plot fifth channel data if available
                if ~isempty(data5)
                    distances5 = sqrt(sum((data5 - centroid).^2, 2));
                    azimuth5 = atan2(data5(:,2) - centroid(2), data5(:,1) - centroid(1));
                    vicinity_threshold = 500; % Define the vicinity threshold
                    vicinity_indices = distances5 <= vicinity_threshold;

                    distances5_vicinity = distances5(vicinity_indices);
                    azimuth5_vicinity = azimuth5(vicinity_indices);

                    % Plot the polar scatter for the fifth channel
                    plotline5 = polarscatter(pax, azimuth5_vicinity, distances5_vicinity, 'o', 'MarkerEdgeColor', color5, 'DisplayName', app.filename5);
                    plotline5.LineWidth = 1;
                end
            end

            % Set title for the polar plot
            title(pax, 'Ring Analysis from Centroid');
            hold(pax, 'off');
            legend(pax, 'location', 'northwest')
        end


        function [x, y, z, t] = extractBeadMovementNormalized(app, localizations)
            x = [];
            y = [];
            z = [];
            t = [];
            for i = 1:numel(localizations)
                pos = localizations(i).pos;
                x = [x; pos(1)];
                y = [y; pos(2)];
                z = [z; pos(3)];
                t = [t; localizations(i).tim]; % Assuming 'tim' records the time stamps
            end
            % Normalize to the first localization
            x = (x - x(1)) * 1e9; % Convert to nm
            y = (y - y(1)) * 1e9; % Convert to nm
            z = (z - z(1)) * 1e9; % Convert to nm
            t = t - t(1); % Normalize time to start at 0
        end

        function visualizeBeadMovement(app, tempText)
            % Create and show loading dialog
            loadingDialog = uiprogressdlg(app.DBSCAN, 'Title', 'Loading...', ...
                'Message', 'Preparing bead movement visualization. This may take a moment...', ...
                'Indeterminate','on');

            try
                stainingFields = fieldnames(app.Zarr);
                colors = lines(length(stainingFields));

                % Calculate max observation time for each staining
                maxObservationTimes = containers.Map();
                for i = 1:length(stainingFields)
                    stainingName = stainingFields{i};
                    beadFields = fieldnames(app.Zarr.(stainingName));
                    maxTime = 0;
                    for j = 1:length(beadFields)
                        beadName = beadFields{j};
                        localizations = app.Zarr.(stainingName).(beadName);
                        [~, ~, ~, t] = app.extractBeadMovementNormalized(localizations);
                        maxTime = max(maxTime, max(t));
                    end
                    maxObservationTimes(stainingName) = maxTime;
                end

                % Count total number of beads
                totalBeads = 0;
                for i = 1:length(stainingFields)
                    stainingName = stainingFields{i};
                    beadFields = fieldnames(app.Zarr.(stainingName));
                    totalBeads = totalBeads + length(beadFields);
                end

                % Calculate grid layout
                cols = 3;  % Fixed 3 columns
                rows = ceil(totalBeads / cols);

                % Calculate axes size
                axesWidth = 200;  % Fixed width for each plot
                axesHeight = 200;  % Fixed height for each plot
                hSpacing = 20;  % Horizontal spacing between plots
                vSpacing = 20;  % Vertical spacing between plots

                % Calculate content size
                contentWidth = cols * (axesWidth + hSpacing) + hSpacing;
                contentHeight = rows * (axesHeight + vSpacing) + vSpacing;

                beadCount = 0;
                for i = 1:length(stainingFields)
                    stainingName = stainingFields{i};
                    beadFields = fieldnames(app.Zarr.(stainingName));

                    for j = 1:length(beadFields)
                        beadCount = beadCount + 1;
                        beadName = beadFields{j};

                        % Calculate position for this axes
                        col = mod(beadCount - 1, cols);
                        row = floor((beadCount - 1) / cols);
                        left = hSpacing + col * (axesWidth + hSpacing);
                        bottom = contentHeight - (row + 1) * (axesHeight + vSpacing);

                        % Create new axes for this bead
                        ax = uiaxes(app.StabilizationTab, 'Position', [left, bottom, axesWidth, axesHeight]);

                        hold(ax, 'on');

                        localizations = app.Zarr.(stainingName).(beadName);
                        [x, y, z, t] = app.extractBeadMovementNormalized(localizations);

                        plot(ax, t, x, 'Color', colors(i,:), 'LineStyle', '-', 'DisplayName', 'X');
                        plot(ax, t, y, 'Color', colors(i,:), 'LineStyle', '--', 'DisplayName', 'Y');
                        plot(ax, t, z, 'Color', colors(i,:), 'LineStyle', ':', 'DisplayName', 'Z');

                        xlabel(ax, 'Time (s)');
                        ylabel(ax, 'Deviation (nm)');
                        title(ax, sprintf('%s - %s', stainingName, beadName), 'Interpreter', 'none');
                        legend(ax, 'show', 'Location', 'northeast');
                        grid(ax, 'on');

                        % Set y-axis limits symmetrically
                        maxDeviation = max(abs([x; y; z]));
                        ylim(ax, [-maxDeviation*1.1, maxDeviation*1.1]);

                        % Set x-axis limit to the maximum observation time for this staining
                        xlim(ax, [0, maxObservationTimes(stainingName)]);

                        hold(ax, 'off');
                    end
                end

                % Set the tab's content size to enable scrolling
                app.StabilizationTab.UserData = struct('ContentSize', [contentWidth contentHeight]);

            catch e
                % Display error message
                errordlg(['An error occurred: ' e.message], 'Error');
            end

            % Close the loading dialog
            close(loadingDialog);

            % Delete the temporary text
            delete(tempText);
        end

        function updateMulticolorTabFromCorrections(app)
            for i = 1:length(app.ReferenceStainingDropDown.Items)
                channelName = ['Channel_', num2str(i)];
                displayChannelName = ['Channel ', num2str(i)];

                % Update ChannelFileNames
                app.ChannelFileNames.(channelName) = app.ReferenceStainingDropDown.Items{i};

                % Update ChannelEnableStates
                app.ChannelEnableStates.(channelName) = (i == 1);  % Only Channel 1 is enabled by default

                % Update ChannelCorrections
                if isfield(app.ChannelCorrections, channelName)
                    corrections = app.ChannelCorrections.(channelName);
                    % Check if the corrections structure has uppercase or lowercase field names
                    if isfield(corrections, 'X')
                        app.ChannelCorrections.(channelName) = struct('X', corrections.X, 'Y', corrections.Y, 'Z', corrections.Z);
                    elseif isfield(corrections, 'x')
                        app.ChannelCorrections.(channelName) = struct('X', corrections.x, 'Y', corrections.y, 'Z', corrections.z);
                    else
                        % If neither exists, initialize with zeros
                        app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);
                    end
                else
                    app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);
                end
            end

            % Update channel details for the first channel
            app.updateChannelDetails('Channel 1');

            % Update the ChannelListBox
            app.updateChannelListBox();
        end

  function updateAlignment(app)
    if isempty(fieldnames(app.ChannelCorrections))
        return;  % No correction data available
    end

    for i = 1:length(app.ChannelListBox.Items)
        channelName = ['Channel_', num2str(i)];
        dataField = ['Data', num2str(i)];
        dataFieldOff = [dataField, '_off'];

        if isfield(app.ChannelCorrections, channelName)
            correctionX = app.ChannelCorrections.(channelName).X;
            correctionY = app.ChannelCorrections.(channelName).Y;
            correctionZ = app.ChannelCorrections.(channelName).Z;

            % Apply corrections to the corresponding microscopy data if it exists
            if ~isempty(app.(dataField))
                app.(dataFieldOff) = app.(dataField);
                app.(dataFieldOff)(:,1) = app.(dataFieldOff)(:,1) + correctionX;
                app.(dataFieldOff)(:,2) = app.(dataFieldOff)(:,2) + correctionY;
                app.(dataFieldOff)(:,3) = app.(dataFieldOff)(:,3) + correctionZ;
            end
        end
    end

    % Update plots or other UI elements as needed
    app.updatePlots2();
end

        function validatedBeads = validateBeads(app, data)
            validatedBeads = struct();
            stainingFields = fieldnames(data);
            for i = 1:numel(stainingFields)
                stainingName = stainingFields{i};
                staining = data.(stainingName);
                durations = [];
                beadFields = fieldnames(staining);
                for j = 1:numel(beadFields)
                    beadName = beadFields{j};
                    localizations = staining.(beadName);
                    timeStamps = [localizations.tim];
                    if isempty(timeStamps)
                        duration = 0;
                    else
                        duration = max(timeStamps) - min(timeStamps);
                    end
                    durations = [durations; duration];
                end

                % Threshold to identify beads with durations close to the maximum
                maxDuration = max(durations);
                threshold = maxDuration * 0.95;
                selectedBeads = beadFields(durations >= threshold);
                validatedBeads.(stainingName) = selectedBeads;
            end
        end

        function beadAlignmentData = storeBeadAlignmentData(app, data, validatedBeads)
            beadAlignmentData = struct();
            stainingFields = fieldnames(data);
            for i = 1:numel(stainingFields)
                stainingName = stainingFields{i};
                beadAlignmentData.(stainingName) = struct();
                validBeadsForStaining = validatedBeads.(stainingName);
                for j = 1:numel(validBeadsForStaining)
                    beadName = validBeadsForStaining{j};
                    localizations = data.(stainingName).(beadName);
                    if ~isempty(localizations)
                        pos = localizations(1).pos * 1e9;  % Convert to nm
                        pos(3) = pos(3) * 0.7;  % Apply z-correction factor
                        beadAlignmentData.(stainingName).(beadName) = pos;
                    end
                end
            end
        end

        % Modify the calculateCorrections function
        function calculateCorrections(app)
            referenceStainingName = app.ReferenceStainingDropDown.Value;
            stainingFields = app.ReferenceStainingDropDown.Items;

            for i = 1:numel(stainingFields)
                stainingName = stainingFields{i};
                channelName = ['Channel_', num2str(i)];

                if ~strcmp(stainingName, referenceStainingName)
                    corrections = app.calculateCorrectionForStaining(app.BeadAlignmentData.(referenceStainingName), app.BeadAlignmentData.(stainingName));
                    app.ChannelCorrections.(channelName) = corrections;
                else
                    app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);
                end

                % Update ChannelFileNames
                app.ChannelFileNames.(channelName) = stainingName;
            end
        end

        function corrections = calculateCorrectionForStaining(app, referenceBeads, currentBeads)
            commonBeadNames = intersect(fieldnames(referenceBeads), fieldnames(currentBeads));
            differencesX = [];
            differencesY = [];
            differencesZ = [];

            for i = 1:numel(commonBeadNames)
                beadName = commonBeadNames{i};
                refPos = referenceBeads.(beadName);
                currentPos = currentBeads.(beadName);
                differencesX(end+1) = currentPos(1) - refPos(1);
                differencesY(end+1) = currentPos(2) - refPos(2);
                differencesZ(end+1) = currentPos(3) - refPos(3);
            end

            corrections = struct('x', -mean(differencesX), 'y', -mean(differencesY), 'z', -mean(differencesZ));
        end

        function visualizeBeadLocalizations(app)
            cla(app.LocalizationAxes);
            cla(app.DeviationAxes);

            hold(app.LocalizationAxes, 'on');
            hold(app.DeviationAxes, 'on');

            stainingFields = fieldnames(app.Zarr);
            referenceStainingName = app.ReferenceStainingDropDown.Value;

            % Reorder stainingFields to put reference staining first
            referenceIndex = find(strcmp(stainingFields, referenceStainingName));
            stainingFields = [stainingFields(referenceIndex); stainingFields(1:referenceIndex-1); stainingFields(referenceIndex+1:end)];

            colors = lines(numel(stainingFields));

            deviationData = struct('x', [], 'y', [], 'z', []);
            xTicks = [];

            for i = 1:numel(stainingFields)
                stainingName = stainingFields{i};
                staining = app.Zarr.(stainingName);
                beadFields = app.validatedBeads.(stainingName);

                allX = []; allY = []; allZ = [];
                for j = 1:numel(beadFields)
                    beadName = beadFields{j};
                    localizations = staining.(beadName);
                    for k = 1:numel(localizations)
                        pos = localizations(k).pos * 1e9;  % Convert to nm
                        allX(end+1) = pos(1);
                        allY(end+1) = pos(2);
                        allZ(end+1) = pos(3);
                    end
                end

                scatter3(app.LocalizationAxes, allX, allY, allZ, 20, colors(i,:), 'filled');

                deviationsX = [];
                deviationsY = [];
                deviationsZ = [];

                for j = 1:numel(beadFields)
                    beadName = beadFields{j};
                    if isfield(app.BeadAlignmentData.(referenceStainingName), beadName) && isfield(app.BeadAlignmentData.(stainingName), beadName)
                        refPos = app.BeadAlignmentData.(referenceStainingName).(beadName);
                        currentPos = app.BeadAlignmentData.(stainingName).(beadName);
                        deviationsX(end+1) = currentPos(1) - refPos(1);
                        deviationsY(end+1) = currentPos(2) - refPos(2);
                        deviationsZ(end+1) = currentPos(3) - refPos(3);
                    end
                end

                if strcmp(stainingName, referenceStainingName)
                    % For reference staining, all deviations are 0
                    deviationData.x = [deviationData.x; [0 0 0]];
                    deviationData.y = [deviationData.y; [0 0 0]];
                    deviationData.z = [deviationData.z; [0 0 0]];
                else
                    deviationData.x = [deviationData.x; [i-1 mean(deviationsX) std(deviationsX)/sqrt(length(deviationsX))]];
                    deviationData.y = [deviationData.y; [i-1 mean(deviationsY) std(deviationsY)/sqrt(length(deviationsY))]];
                    deviationData.z = [deviationData.z; [i-1 mean(deviationsZ) std(deviationsZ)/sqrt(length(deviationsZ))]];
                end
                xTicks(end+1) = i-1;
            end

            xlabel(app.LocalizationAxes, 'X (nm)');
            ylabel(app.LocalizationAxes, 'Y (nm)');
            zlabel(app.LocalizationAxes, 'Z (nm)');
            title(app.LocalizationAxes, 'All Bead Localizations');
            legend(app.LocalizationAxes, stainingFields);
            grid(app.LocalizationAxes, 'on');
            view(app.LocalizationAxes, 3);

            errorbar(app.DeviationAxes, deviationData.x(:,1), deviationData.x(:,2), deviationData.x(:,3), 'r', 'LineWidth', 1);
            errorbar(app.DeviationAxes, deviationData.y(:,1), deviationData.y(:,2), deviationData.y(:,3), 'g', 'LineWidth', 1);
            errorbar(app.DeviationAxes, deviationData.z(:,1), deviationData.z(:,2), deviationData.z(:,3), 'b', 'LineWidth', 1);

            xlabel(app.DeviationAxes, 'Staining');
            ylabel(app.DeviationAxes, 'Deviation (nm)');
            title(app.DeviationAxes, 'Localization Deviation of Verified Beads');
            set(app.DeviationAxes, 'XTick', xTicks, 'XTickLabel', stainingFields);
            xtickangle(app.DeviationAxes, 45);
            legend(app.DeviationAxes, {'X', 'Y', 'Z'});
            grid(app.DeviationAxes, 'on');

            hold(app.LocalizationAxes, 'off');
            hold(app.DeviationAxes, 'off');
        end


        function updateMulticolorPlot(app)
            cla(app.UIAxes7);
            hold(app.UIAxes7, 'on');

            dataNames = fieldnames(app.MicroscopyData);
            colors = lines(length(dataNames));

            for i = 1:length(dataNames)
                dataName = dataNames{i};
                data = app.MicroscopyData.(dataName);
                scatter3(app.UIAxes7, data(:,1), data(:,2), data(:,3), 20, colors(i,:), 'filled');
            end

            hold(app.UIAxes7, 'off');
            legend(app.UIAxes7, dataNames);
            title(app.UIAxes7, 'Multicolor MINFLUX Data');
            xlabel(app.UIAxes7, 'X (nm)');
            ylabel(app.UIAxes7, 'Y (nm)');
            zlabel(app.UIAxes7, 'Z (nm)');
            view(app.UIAxes7, 3);
        end

        function ExportButtonPushed(app, event)
            % Prepare data for export
            dataFields = {'Data1', 'Data2_off', 'Data3_off', 'Data4_off', 'Data5_off'};
            channels = {'_first_channel.tif', '_second_channel.tif', '_third_channel.tif', '_fourth_channel.tif', '_fifth_channel.tif'};

            % Get original file paths
            originalFilePaths = cell(1, 5);
            for i = 1:5
                channelName = ['Channel_', num2str(i)];
                if isfield(app.ChannelFileNames, channelName)
                    originalFilePaths{i} = fullfile(app.ChannelFileNames.(channelName));
                end
            end

            exportData = cell(1, 5);

            % Ask user which formats to export
            exportOptions = questdlg('Choose export format(s):', 'Export Options', ...
                'TIFF', 'MAT', 'Both', 'Both');

            for i = 1:length(dataFields)
                channelName = ['Channel_', num2str(i)];
                if ~isempty(app.(dataFields{i})) && (i == 1 || app.ChannelEnableStates.(channelName))
                    data = app.(dataFields{i});

                    % Determine if DBSCAN should be applied
                    applyDBSCAN = app.DBSCANCheckBox.Value && i > 1;

                    % Apply DBSCAN if the checkbox is checked (only for non-reference data)
                    if applyDBSCAN
                        epsilon = app.EpsilonSpinner.Value * 10;
                        minPts = app.MinPtsSpinner.Value;
                        [idx] = dbscan(data, epsilon, minPts);
                        isNoise = (idx == -1);
                        data = data(~isNoise, :);
                    end

                    % Store data for TIFF export
                    exportData{i} = data * 1e-9;  % Convert to meters for TIFF export

                    % Export as MAT if selected
                    if strcmp(exportOptions, 'MAT') || strcmp(exportOptions, 'Both')
                        if ~isempty(originalFilePaths{i})
                            app.saveCorrectedData(originalFilePaths{i}, data, applyDBSCAN);
                        else
                            warndlg(['No original file path found for channel ', num2str(i), '. MAT file not saved for this channel.'], 'Warning');
                        end
                    end
                end
            end

            % Export as TIFF if selected
            if strcmp(exportOptions, 'TIFF') || strcmp(exportOptions, 'Both')
                app.exportAsTIFF(exportData, channels);
            end

            % Inform user that export is complete
    msgbox('Export completed successfully. Files have been saved to the selected directory.', 'Export Complete');
        end

        function saveCorrectedData(app, fullFilePath, data, isDBSCANEnabled)
            % Extract filename and path
            [path, name, ext] = fileparts(fullFilePath);

            % Create new filename based on whether DBSCAN was applied
            if isDBSCANEnabled
                correctedFilename = fullfile(path, [name, '_drift_corrected_DBSCAN', ext]);
            else
                correctedFilename = fullfile(path, [name, '_drift_corrected', ext]);
            end

            % Try loading the existing data structure and handle potential errors
            try
                S = load(fullFilePath); % Load the entire structure
            catch ME
                errordlg(['Failed to load the original data file: ', fullFilePath], 'Load Error');
                return;
            end

            % Ensure the expected data structure exists within S
            if ~isfield(S, 'itr') || ~isfield(S.itr, 'loc') || size(S.itr.loc, 2) < 10 || size(S.itr.loc, 3) < 3
                errordlg(['Data structure not as expected in ', fullFilePath], 'Structure Error');
                return;
            end

            % Get the original number of localizations
            originalCount = size(S.itr.loc, 1);

            % Ensure data is in the correct format (n x 3)
            if size(data, 2) ~= 3
                data = reshape(data, [], 3);
            end

            if isDBSCANEnabled
                % DBSCAN was applied, we need to update only the non-filtered points
                % Create a new structure to hold the filtered data
                filteredS = S;
                filteredS.itr.loc = zeros(size(data, 1), size(S.itr.loc, 2), size(S.itr.loc, 3));

                % Find the corresponding points in the original data
                for i = 1:size(data, 1)
                    [~, idx] = min(sum((squeeze(S.itr.loc(:,10,:)) - data(i,:)).^2, 2));
                    filteredS.itr.loc(i,:,:) = S.itr.loc(idx,:,:);
                end

                % Update the filtered structure with the new data
                filteredS.itr.loc(:,10,1) = data(:,1);
                filteredS.itr.loc(:,10,2) = data(:,2);
                filteredS.itr.loc(:,10,3) = data(:,3) / 0.7; % Undo Z correction

                % Save the filtered and corrected data
                save(correctedFilename, '-struct', 'filteredS', '-v7.3');
            else
                % No DBSCAN filtering, update all points
                S.itr.loc(:,10,1) = data(:,1);
                S.itr.loc(:,10,2) = data(:,2);
                S.itr.loc(:,10,3) = data(:,3) / 0.7; % Undo Z correction

                % Save the corrected data along with the rest of the original structure
                save(correctedFilename, '-struct', 'S', '-v7.3');
            end

            disp(['Saved corrected data to: ', correctedFilename]);
        end

        function exportAsTIFF(app, datasets, channels)
            % Define saturation
            sat = 0.0099;

            % Define boundaries using quantiles across all datasets
            all_data = vertcat(datasets{:});
            Rx = quantile(all_data(:,1), [sat, 1-sat]);
            Ry = quantile(all_data(:,2), [sat, 1-sat]);
            Rz = quantile(all_data(:,3), [sat, 1-sat]);

            % Define minimum boundaries
            Rmin = 200e-9;
            if diff(Rx) < Rmin
                Rx = Rx + (Rmin - diff(Rx))/2 * [-1, 1];
            end
            if diff(Ry) < Rmin
                Ry = Ry + (Rmin - diff(Ry))/2 * [-1, 1];
            end
            if (diff(Rz) < Rmin) && (diff(Rz) > 1e-9)
                Rz = Rz + (Rmin - diff(Rz))/2 * [-1, 1];
            end

            sx = 7e-9;
            sy = 7e-9;
            sz = 7e-9;

            % Get dimensions
            Nx = ceil(diff(Rx) / sx);
            Ny = ceil(diff(Ry) / sy);
            Nz = ceil(diff(Rz) / sz);

            fwhm = 5e-9;
            if numel(fwhm) == 1
                fwhm = fwhm * [1, 1, 1];
            end

            wx = fwhm(1) / sx;
            wy = fwhm(2) / sy;
            wz = fwhm(3) / sz;

            L = ceil(2 * max([wx, wy, wz]));

            % Small grid
            g = -L:L;
            [xk, yk, zk] = meshgrid(g, g, g);

            % Pad the volume
            pad = L+1; % Increase padding

            % Output grid
            xi = Rx(1) + (0:Nx-1) * sx;
            yi = Ry(1) + (0:Ny-1) * sy;
            zi = Rz(1) + (0:Nz-1) * sz;

            for dataset_idx = 1:length(datasets)
                current_data = datasets{dataset_idx};
                if isempty(current_data)
                    continue;
                end

                % Get position
                px = (current_data(:,1) - Rx(1)) / sx;
                py = (current_data(:,2) - Ry(1)) / sy;
                pz = (current_data(:,3) - Rz(1)) / sz;

                oa = zeros(Nx+2*pad, Ny+2*pad, Nz+2*pad, 'single');

                % Adjust indices for padding
                ix = round(px) + pad;
                iy = round(py) + pad;
                iz = round(pz) + pad;

                % Main loop
                for i = 1:numel(ix)
                    xi = ix(i);
                    yi = iy(i);
                    zi = iz(i);
                    dx = px(i) + L - xi;
                    dy = py(i) + L - yi;
                    dz = pz(i) + L - zi;

                    gx = int32(xi + g);
                    gy = int32(yi + g);
                    gz = int32(zi + g);

                    % Ensure we're within bounds
                    valid_x = gx > 0 & gx <= size(oa, 1);
                    valid_y = gy > 0 & gy <= size(oa, 2);
                    valid_z = gz > 0 & gz <= size(oa, 3);

                    gx = gx(valid_x);
                    gy = gy(valid_y);
                    gz = gz(valid_z);

                    [gxi, gyi, gzi] = meshgrid(gx, gy, gz);

                    gauss = exp(-4*log(2)*((xk(valid_y,valid_x,valid_z)-dx).^2 / wx^2 + ...
                        (yk(valid_y,valid_x,valid_z)-dy).^2 / wy^2 + ...
                        (zk(valid_y,valid_x,valid_z)-dz).^2 / wz^2));

                    linear_indices = sub2ind(size(oa), gxi(:), gyi(:), gzi(:));
                    oa(linear_indices) = oa(linear_indices) + gauss(:);
                end

                % Save output as TIFF
                output_filename = ['exported', channels{dataset_idx}];
                app.save_as_tiff(oa(pad+1:end-pad, pad+1:end-pad, pad+1:end-pad), xi, yi, zi, output_filename);
            end
        end

        function save_as_tiff(app, data, x, y, z, filename)
            % Ensure data is in the correct format (uint16)
            data_uint16 = uint16(rescale(data, 0, 65535));

            % Check if file exists and delete it if it does
            if exist(filename, 'file')
                delete(filename);
            end

            % Get dimensions
            [height, width, depth] = size(data_uint16);

            % Open a new TIFF file
            t = Tiff(filename, 'w');

            % Define tags
            tagstruct.ImageLength = height;
            tagstruct.ImageWidth = width;
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.BitsPerSample = 16;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.RowsPerStrip = height;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.Software = 'MATLAB';
            tagstruct.ImageDescription = sprintf('X range: %e to %e\nY range: %e to %e\nZ range: %e to %e', ...
                min(x), max(x), min(y), max(y), min(z), max(z));

            % Write each slice
            for i = 1:depth
                t.setTag(tagstruct);
                t.write(data_uint16(:,:,i));
                if i < depth
                    t.writeDirectory();
                end
            end

            % Close the TIFF file
            t.close();

            disp(['Data saved as ', filename]);
        end

 function updateClusterInfo(app)
    % Get unique clusters (excluding noise which is typically 0)
    validClusters = unique(app.Clusters1(app.Clusters1 > 0));
    
    % Apply exclusion threshold if enabled
    if app.EnableExclusion.Value
        exclusionThreshold = app.ExclNum.Value;
        validClusters = validClusters(arrayfun(@(x) sum(app.Clusters1 == x), validClusters) >= exclusionThreshold);
    end

    numValidClusters = numel(validClusters);
    
    % Calculate traces if not already done
    app.calculateTraceData();
    
    % Calculate nearest neighbor distances
    nearestNeighborDistances = app.calculateNearestNeighborDistances();

    tableData = zeros(numValidClusters, 7);  % Now 7 columns to include radius
    
    % Initialize a cell array to store RDF data for each cluster
    app.ClusterRDFs = cell(numValidClusters, 1);
    
    for i = 1:numValidClusters
        clusterIndex = validClusters(i);
        
        % Count localizations
        locCount = sum(app.Clusters1 == clusterIndex);
        
        % Get cluster points
        clusterPoints = app.Data1(app.Clusters1 == clusterIndex & app.IsNoise1 == 0, :);
        
        % Calculate radius
        radius = app.calculateClusterRadius(clusterPoints);
        
        % Calculate volume and surface
        if size(clusterPoints, 1) > 3
            [K, volume] = convhull(clusterPoints);
            surface = app.calculateSurfaceArea(clusterPoints, K);
        else
            volume = NaN;
            surface = NaN;
        end
        
        % Count traces
        traceCount = sum(app.trace_clusters1 == clusterIndex);
        
        % Calculate RDF for this cluster
        if size(clusterPoints, 1) > 1
            [r, g_r] = app.calculateRDF(clusterPoints);
            app.ClusterRDFs{i} = struct('r', r, 'g_r', g_r);
        else
            app.ClusterRDFs{i} = struct('r', [], 'g_r', []);
        end
        
        % Add all data to the table, including radius
        tableData(i, :) = [clusterIndex, locCount, traceCount, volume, surface, nearestNeighborDistances(i), radius];
    end
    
    % Update the table
    app.UITable.Data = tableData;
    app.UITable.ColumnName = {'Cluster', 'Localizations', 'Traces', 'Volume (nm³)', 'Surface (nm²)', 'Avg NN Dist (nm)', 'Radius (nm)'};
    
    % Store the results
    app.ClusterVolumes = tableData(:, 4);
    app.ClusterSurfaces = tableData(:, 5);
    app.ClusterRadii = tableData(:, 7);
    
    % Print debug information
    disp(['Total localizations: ', num2str(sum(tableData(:,2)))]);
    disp(['Total traces: ', num2str(sum(tableData(:,3)))]);
    disp(['Number of clusters: ', num2str(size(tableData,1))]);

    app.updateMultiChannelClusterInfo();
end
function [r, g_r] = calculateRDF(app, points)
    % Define max_distance and num_bins here instead of passing them as arguments
    max_distance = max(pdist(points));
    num_bins = 50; % Adjust as needed

    % Calculate the center of the cluster
    center = mean(points, 1);
    
    % Calculate distances from each point to the center
    distances = sqrt(sum((points - center).^2, 2));
    
    % Create distance bins
    r = linspace(0, max_distance, num_bins);
    dr = r(2) - r(1);
    
    % Calculate RDF
    g_r = zeros(1, num_bins-1);
    volume = 4/3 * pi * max_distance^3;
    n_total = size(points, 1);
    
    for i = 1:(num_bins-1)
        r_low = r(i);
        r_high = r(i+1);
        n_in_shell = sum(distances >= r_low & distances < r_high);
        shell_volume = 4/3 * pi * (r_high^3 - r_low^3);
        g_r(i) = (n_in_shell / n_total) * (volume / shell_volume);
    end
    
    % Use bin centers for r
    r = (r(1:end-1) + r(2:end)) / 2;
end
        function averageNearestNeighborDistances = calculateNearestNeighborDistances(app)
            validClusters = unique(app.Clusters1(app.Clusters1 > 0));
            averageNearestNeighborDistances = zeros(length(validClusters), 1);

            for i = 1:length(validClusters)
                clusterIndex = validClusters(i);

                % Get traces for this cluster
                clusterTraces = app.trace_data1(app.trace_clusters1 == clusterIndex, :);

                if size(clusterTraces, 1) > 1
                    % Calculate pairwise distances between all traces in the cluster
                    distances = pdist(clusterTraces);

                    % Reshape distance matrix
                    distMatrix = squareform(distances);

                    % Set diagonal to inf to ignore self-distances
                    distMatrix(logical(eye(size(distMatrix)))) = inf;

                    % Find minimum distance for each trace (nearest neighbor)
                    minDistances = min(distMatrix, [], 2);

                    % Calculate average nearest neighbor distance for this cluster
                    averageNearestNeighborDistances(i) = mean(minDistances);
                else
                    % If cluster has only one trace, set distance to NaN
                    averageNearestNeighborDistances(i) = NaN;
                end
            end
        end

        function surfaceArea = calculateSurfaceArea(app, points, K)
            surfaceArea = 0;
            for i = 1:size(K, 1)
                % Triangle vertices
                pt1 = points(K(i, 1), :);
                pt2 = points(K(i, 2), :);
                pt3 = points(K(i, 3), :);

                % Triangle sides
                side1 = norm(pt1 - pt2);
                side2 = norm(pt2 - pt3);
                side3 = norm(pt3 - pt1);

                % Semi-perimeter
                s = (side1 + side2 + side3) / 2;

                % Area using Heron's formula
                surfaceArea = surfaceArea + sqrt(s * (s - side1) * (s - side2) * (s - side3));
            end
        end


        function radius = calculateClusterRadius(app, points)
            % Calculate the centroid (center of mass) of the cluster
            centroid = mean(points, 1);

            % Calculate squared distances from each point to the centroid
            squared_distances = sum((points - centroid).^2, 2);

            % Calculate the radius of gyration
            radius_of_gyration = sqrt(mean(squared_distances));

            % Calculate the standard deviation of distances
            std_distances = std(sqrt(squared_distances));

            % Calculate the skewness of the distance distribution
            skewness = mean((sqrt(squared_distances) - radius_of_gyration).^3) / std_distances^3;

            % Adjust the radius based on the skewness
            if skewness > 0
                % For positively skewed distributions (elongated clusters),
                % increase the radius slightly
                radius = radius_of_gyration * (1 + 0.1 * skewness);
            else
                % For negatively skewed or symmetric distributions,
                % use the radius of gyration as is
                radius = radius_of_gyration;
            end
        end

     function updateMultiChannelClusterInfo(app)
    % Get unique clusters (excluding noise which is typically 0)
    validClusters = unique(app.Clusters1(app.Clusters1 > 0));
    
    % Apply exclusion threshold if enabled
    if app.EnableExclusion.Value
        exclusionThreshold = app.ExclNum.Value;
        validClusters = validClusters(arrayfun(@(x) sum(app.Clusters1 == x), validClusters) >= exclusionThreshold);
    end
    
    numValidClusters = numel(validClusters);
    
    % Initialize column names and data arrays
    columnNames = {'Cluster', 'Count Ch1', 'Trace Count Ch1'};
    tableData = zeros(numValidClusters, 3);
    
    % Populate data for Channel 1 (main channel)
    for i = 1:numValidClusters
        clusterIndex = validClusters(i);
        tableData(i, 1) = clusterIndex;
        tableData(i, 2) = sum(app.Clusters1 == clusterIndex);
        tableData(i, 3) = sum(app.trace_clusters1 == clusterIndex);  % Add this line to include trace count for Channel 1
    end
    
    % Function to add channel data if it exists
    function addChannelData(channelNum, clusters, traceData, traceClusters)
        if ~isempty(clusters) && ~isempty(traceData)
            columnNames = [columnNames, sprintf('Count Ch%d(Loc)', channelNum), sprintf('Count Ch%d(Trc)', channelNum)];
            newData = zeros(numValidClusters, 2);
            for j = 1:numValidClusters
                clusterIndex = validClusters(j);
                % Get the bounding box of the cluster in the main channel
                clusterPoints = app.Data1(app.Clusters1 == clusterIndex, :);
                if ~isempty(clusterPoints)
                    minBound = min(clusterPoints) - [app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ]/2;
                    maxBound = max(clusterPoints) + [app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ]/2;
                    
                    % Count localizations within the bounding box
                    inBox = all(clusters >= minBound & clusters <= maxBound, 2);
                    newData(j, 1) = sum(inBox);
                    
                    % Count traces within the bounding box
                    traceInBox = all(traceData >= minBound & traceData <= maxBound, 2);
                    newData(j, 2) = sum(traceInBox);
                end
            end
            tableData = [tableData, newData];
        end
    end

    % Add data for additional channels if they exist
    addChannelData(2, app.Data2_off, app.trace_data2, app.trace_clusters2);
    addChannelData(3, app.Data3_off, app.trace_data3, app.trace_clusters3);
    addChannelData(4, app.Data4_off, app.trace_data4, app.trace_clusters4);
    addChannelData(5, app.Data5_off, app.trace_data5, app.trace_clusters5);
    
    % Update the table
    app.UITableMulti.Data = tableData;
    app.UITableMulti.ColumnName = columnNames;
end
    

        function columnNames = generateColumnNames(app)
            columnNames = {'Cluster'};

            % Main channel
            mainName = 'Main';
            if ~isempty(app.FileNameLabel1.Text)
                mainName = app.extractFilename(app.FileNameLabel1.Text);
            end
            columnNames = [columnNames, {[mainName '(Loc)'], [mainName '(Trc)']}];

            % Other channels
            for i = 2:5
                labelField = ['FileNameLabel', num2str(i)];
                if isprop(app, labelField) && ~isempty(app.(labelField).Text)
                    channelName = app.extractFilename(app.(labelField).Text);
                    columnNames = [columnNames, {[channelName '(Loc)'], [channelName '(Trc)']}];
                end
            end
        end

        function name = extractFilename(app, labelText)
            if startsWith(labelText, 'File:')
                [~, name, ~] = fileparts(labelText(7:end));
            elseif contains(labelText, '(') && contains(labelText, ')')
                name = extractBetween(labelText, '(', ')');
                name = name{1};
            else
                name = labelText;
            end
        end


        function updateChannelDetails(app, displayChannelName)
            channelNumber = str2double(regexp(displayChannelName, '\d+', 'match'));
            channelName = ['Channel_', num2str(channelNumber)];

            app.ChannelNameLabel.Text = displayChannelName;

            if isfield(app.ChannelFileNames, channelName)
                app.ChannelFileLabel.Text = app.ChannelFileNames.(channelName);
            else
                app.ChannelFileLabel.Text = '';
            end

            if channelNumber == 1
                app.ChannelEnableCheckbox.Value = true;
                app.ChannelEnableCheckbox.Enable = 'off';
            else
                if isfield(app.ChannelEnableStates, channelName)
                    app.ChannelEnableCheckbox.Value = app.ChannelEnableStates.(channelName);
                else
                    app.ChannelEnableCheckbox.Value = true;  % Set to true by default for new channels
                end
                app.ChannelEnableCheckbox.Enable = 'on';
            end

            % Update correction fields with the stored values for the selected channel
            if isfield(app.ChannelCorrections, channelName)
                app.XCorrectionField.Value = app.ChannelCorrections.(channelName).X;
                app.YCorrectionField.Value = app.ChannelCorrections.(channelName).Y;
                app.ZCorrectionField.Value = app.ChannelCorrections.(channelName).Z;
            else
                app.XCorrectionField.Value = 0;
                app.YCorrectionField.Value = 0;
                app.ZCorrectionField.Value = 0;
            end

            % Enable correction fields for editing
            app.XCorrectionField.Enable = 'on';
            app.YCorrectionField.Enable = 'on';
            app.ZCorrectionField.Enable = 'on';
        end

   function UpdateCorrection(app, correctionType, newValue)
    selectedChannel = app.ChannelListBox.Value;
    channelNumber = str2double(regexp(selectedChannel, '\d+', 'match'));
    channelName = ['Channel_', num2str(channelNumber)];

    % Ensure newValue is a number
    if isnan(newValue)
        warning('Invalid correction value. Please enter a number.');
        return;
    end

    % Update the stored correction value for the selected channel
    if ~isfield(app.ChannelCorrections, channelName)
        app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);
    end
    app.ChannelCorrections.(channelName).(correctionType) = newValue;

    % Apply the correction to the data
    dataField = ['Data', num2str(channelNumber)];
    dataFieldOff = [dataField, '_off'];
    
    if ~isempty(app.(dataField))
        app.(dataFieldOff) = app.(dataField);
        app.(dataFieldOff)(:,1) = app.(dataFieldOff)(:,1) + app.ChannelCorrections.(channelName).X;
        app.(dataFieldOff)(:,2) = app.(dataFieldOff)(:,2) + app.ChannelCorrections.(channelName).Y;
        app.(dataFieldOff)(:,3) = app.(dataFieldOff)(:,3) + app.ChannelCorrections.(channelName).Z;
    end

    % Update the visualization
    app.updatePlots2();
    app.updateAlignment();


end

function suffix = getChannelSuffix(app, channelNumber)
            suffixes = {'Sec', 'Trd', 'Qrt', 'Qnt'};
            suffix = suffixes{channelNumber - 1};
        end

        function selectColor(app, colorType)
            color = uisetcolor();
            if length(color) == 3  % Check if a color was selected
                switch colorType
                    case {'Channel1', 'Channel2', 'Channel3', 'Channel4', 'Channel5'}
                        channelIndex = str2double(colorType(end));
                        app.ChannelColorButtons{channelIndex}.BackgroundColor = color;
                end
            end
        end

        function applyColors(app)
    % Update channel colors
    for i = 1:min(5, length(app.ChannelColors))
        if i <= length(app.ChannelColorButtons) && isvalid(app.ChannelColorButtons{i})
            app.ChannelColors{i} = app.ChannelColorButtons{i}.BackgroundColor;
        end
    end
    
    % Set the flag to use custom colors
    app.UseCustomColors = true;
    
    % Update the plots with new colors
    try
        app.updatePlots();
        app.updatePlots2();
        app.plotSelectedClusters();
        app.plotSelectedClusters2();
    catch e
        disp(['Error updating plots: ' e.message]);
    end
    
    % Refresh the app to ensure all changes are visible
    drawnow;
    
    % Close the color preferences window if it's open
    if isvalid(app.DBSCAN)
        colorFig = findobj(allchild(app.DBSCAN), 'Type', 'Figure', 'Name', 'Channel Color Preferences');
        if ~isempty(colorFig)
            close(colorFig);
        end
    end
end
function resetColors(app)
    % Reset to default colors
    app.UseCustomColors = false;
    
    % Reset the ChannelColors to their default values
    app.ChannelColors = {'k', 'cyan', 'red', 'magenta', 'green'};
    app.currentcolormap = 'parula';

    
    % Update the color buttons in the color preferences window
    if isvalid(app.DBSCAN)
        colorFig = findobj(allchild(app.DBSCAN), 'Type', 'Figure', 'Name', 'Channel Color Preferences');
        if ~isempty(colorFig) && isvalid(colorFig)
            for i = 1:length(app.ChannelColorButtons)
                if isvalid(app.ChannelColorButtons{i})
                    app.ChannelColorButtons{i}.BackgroundColor = app.ChannelColors{i};
                end
            end
        end
    end
    
    % Update all plots
    app.updatePlots();
    app.updatePlots2();
    app.plotSelectedClusters();
    app.plotSelectedClusters2();
    
    % Refresh the app to ensure all changes are visible
    drawnow;
end

function updateColormap(app, newColormap)
    % Update the current colormap property
    app.currentcolormap = newColormap;
    
    % Update all plots that use the colormap
    app.updatePlots();
    app.updatePlots2();
    app.plotSelectedClusters();
    app.plotSelectedClusters2();
end

function resetAppState(app)
    % Clear all data
    app.Data1 = [];
    app.Data2_off = [];
    app.Data3_off = [];
    app.Data4_off = [];
    app.Data5_off = [];
    
    % Reset clusters and noise
    app.Clusters1 = [];
    app.IsNoise1 = [];
    
    % Clear trace data
    app.trace_data1 = [];
    app.trace_data2 = [];
    app.trace_data3 = [];
    app.trace_data4 = [];
    app.trace_data5 = [];
    app.trace_clusters1 = [];
    app.trace_clusters2 = [];
    app.trace_clusters3 = [];
    app.trace_clusters4 = [];
    app.trace_clusters5 = [];
    
    % Reset file names and paths
    app.filename1 = '';
    app.filepath1 = '';
    app.filename2 = '';
    app.filename3 = '';
    app.filename4 = '';
    app.filename5 = '';
    
    % Clear channel information
    app.ChannelFileNames = struct();
    app.ChannelEnableStates = struct();
    app.ChannelCorrections = struct();


    % Reset bead data and related properties
    app.Zarr = [];               
    app.validatedBeads = [];     
    app.BeadAlignmentData = [];  
    app.CorrectionData = [];    
    app.ReferenceStainingDropDown.Items = {'Channel 1'};

    app.ReferenceStainingDropDown.Value = 'Channel 1';
    
    % Reset UI elements
    app.FileNameLabel1.Text = '';
    app.ListBox.Items = {};
    app.ListBox_2.Items = {};
    app.ListBox2.Items = {};
    app.ListBox2_2.Items = {};
    
    % Clear all axes
    cla(app.UIAxes);
    cla(app.UIAxes2);
    cla(app.UIAxes5);
    cla(app.UIAxes5_2);
    cla(app.UIAxes6);
    cla(app.UIAxes7);
    cla(app.UIAxes3);
    cla(app.UIAxes3_2);
    cla(app.DeviationAxes);
    cla(app.LocalizationAxes);

    
    % Reset tables
    app.UITable.Data = {};
    app.UITableMulti.Data = {};
    
    % Reset other properties
    app.allCenteredPoints = [];
    app.ClusterVolumes = [];
    app.ClusterSurfaces = [];
    app.ClusterRDFs = {};
    
    % Clear DBSCAN cache
    app.DBSCANCache = struct();
    
    % Reset ChannelListBox
    app.ChannelListBox.Items = {'Channel 1', 'Add Channel'};
    app.ChannelListBox.Value = 'Channel 1';
    
    % Update channel details
    updateChannelDetails(app, 'Channel 1');
    
    % Reset checkbox visibility
    updateCheckboxVisibility(app);
end

function processBeadDataFile(app, fullPath)
    % Load the MAT file
    loadedData = load(fullPath);
    
    if ~isfield(loadedData, 'data')
        uialert(app.DBSCAN, 'No "data" field found in file', 'File Error');
        return;
    end
    
    % Update the app properties with the loaded data
    [~, fileName, ext] = fileparts(fullPath);
    app.Zarr = loadedData.data;
    app.ZarrLabel.Text = ['File: ', fileName, ext];
    
    % Validate beads and store bead alignment data
    app.validatedBeads = app.validateBeads(app.Zarr);
    app.BeadAlignmentData = app.storeBeadAlignmentData(app.Zarr, app.validatedBeads);
    
    % Update the dropdown menu with staining names.
    stainingFields = fieldnames(app.Zarr);
    if numel(stainingFields) > 1
        [indx, tf] = listdlg('ListString', stainingFields, ...
            'SelectionMode', 'single', ...
            'PromptString', 'Select the reference staining:');
        if tf == 0
            referenceStaining = stainingFields{1};
        else
            referenceStaining = stainingFields{indx};
        end
    else
        referenceStaining = stainingFields{1};
    end
    % Reorder so that the reference staining appears first
    stainingFields = [referenceStaining; stainingFields(~strcmp(stainingFields, referenceStaining))];
    app.ReferenceStainingDropDown.Items = stainingFields;
    app.ReferenceStainingDropDown.Value = referenceStaining;
    
    % Perform corrections and update related visualizations
    app.calculateCorrections();
    app.updateMulticolorTabFromCorrections();
    app.updateAlignment();
    app.visualizeBeadLocalizations();
    app.updateChannelListBox();
end

function closeWelcomeDialog(app, dialogHandle, dontShowChk)
    % Update the property based on checkbox state
    app.ShowWelcomeMessage = ~get(dontShowChk, 'Value');
    close(dialogHandle);
end

function isValid = isValidZarrFolder(app, folderPath)
    try
        % Get all subdirectories in the parent folder (staining folders)
        stainingFolders = dir(fullfile(folderPath, '*'));
        stainingFolders = stainingFolders([stainingFolders.isdir]);
        % Remove '.' and '..' entries
        names = {stainingFolders.name};
        stainingFolders = stainingFolders(~ismember(names, {'.', '..'}));
        
        if isempty(stainingFolders)
            disp('No staining folders found in the parent directory.');
            isValid = false;
            return;
        end
        
        % Check at least one staining folder for expected structure
        foundValidStructure = false;
        for i = 1:length(stainingFolders)
            stainingPath = fullfile(folderPath, stainingFolders(i).name);
            
            % Get all subdirectories in the staining folder (bead folders)
            beadFolders = dir(fullfile(stainingPath, '*'));
            beadFolders = beadFolders([beadFolders.isdir]);
            % Remove '.' and '..' entries
            names = {beadFolders.name};
            beadFolders = beadFolders(~ismember(names, {'.', '..'}));
            
            if isempty(beadFolders)
                continue; % No bead folders in this staining folder, try next one
            end
            
            % Check at least one bead folder for expected files
            for j = 1:length(beadFolders)
                beadPath = fullfile(stainingPath, beadFolders(j).name);
                
                % Check for .zattrs and .zarray files (common in Zarr datasets)
                hasZattrs = exist(fullfile(beadPath, '.zattrs'), 'file') == 2;
                hasZarray = exist(fullfile(beadPath, '.zarray'), 'file') == 2;
                
                if hasZattrs || hasZarray
                    foundValidStructure = true;
                    break; % Found at least one valid bead folder
                end
            end
            
            if foundValidStructure
                break; % Found at least one valid staining folder
            end
        end
        
        isValid = foundValidStructure;
        
        if ~isValid
            disp('No valid Zarr structure found in the selected folder.');
        end
    catch ex
        disp(['Error validating Zarr folder: ' ex.message]);
        isValid = false;
    end
end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ClusterRDFs = {};
            app.figureHandle3D = [];
            app.figureHandle2D = [];
            app.ChannelCorrections = struct();
            app.ChannelFileNames = struct();
            app.ChannelEnableStates = struct();

            % Initialize ChannelColors as a cell array
            app.ChannelColors = {'k', 'cyan', 'red', 'magenta', 'green'};

            % Initialize ChannelColorButtons as a cell array
            app.ChannelColorButtons = cell(1, 5);

% Check if the welcome message should be shown
if app.ShowWelcomeMessage && ~isdeployed
    try
        % Create figure with basic properties
        d = figure('Name', 'Welcome to matFLUX 1.0!', 'Position', [100 100 400 300], ...
                   'NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none');
        
        % Welcome message text with manual line breaks
        welcomeMsg = sprintf(['Steps to begin:\n' ...
                             '1. Click "Load/New" button to load your MINFLUX file.\n' ...
                             '2. Adjust DBSCAN (Epsilon, MinPts) as needed.\n' ...
                             '3. Inspect single clusters.\n' ...
                             '4. Load and align additional channels.\n' ...
                             '5. Explore tabs for further analyses.\n\n' ...
                             'Use the camera icon to save plots.\n' ...
                             'For detailed help, click the Help menu.']);
        
        % Create text control instead of uilabel
        uicontrol('Style', 'text', 'String', welcomeMsg, ...
                  'Position', [20 120 360 150], 'HorizontalAlignment', 'left');
        
        % Create checkbox with standard uicontrol
        dontShowChk = uicontrol('Style', 'checkbox', ...
                                'String', 'Don''t show this message again', ...
                                'Position', [20 80 200 22], 'Value', 0);
        
        % Create OK button
        uicontrol('Style', 'pushbutton', 'String', 'OK', ...
                  'Position', [160 30 80 30], ...
                  'Callback', @(src,event) closeWelcomeDialog(app, d, dontShowChk));
        
        % Make figure visible and modal-like
        set(d, 'Visible', 'on');
        uiwait(d); % Pause execution until the dialog is closed
    catch
        % If there's any error in creating the welcome dialog, just continue
        disp('Welcome dialog could not be displayed');
    end
end

        end

        % Value changed function: EpsilonSpinner
        function EpsilonSpinnerValueChanged(app, event)
            updatePlots(app)
            %DBSCANCheckBoxValueChanged(app);
            app.updateClusterInfo();
            plotSelectedClusters(app)

        end

        % Value changed function: MinPtsSpinner
        function MinPtsSpinnerValueChanged(app, event)
            updatePlots(app);
            %DBSCANCheckBoxValueChanged(app);
            app.updateClusterInfo();
            plotSelectedClusters(app)
        end

        % Button pushed function: LoadNewButton
        function LoadNewButtonPushed(app, event)
        % Check if DBSCAN function is available
    if ~exist('dbscan', 'file')
        errordlg(['DBSCAN function not found. This application requires the Statistics and Machine Learning Toolbox. ' ...
                  'Please install this toolbox.'], 'Missing Dependency');
        return;
    end

    % Store current color and box settings
    currentColors = app.ChannelColors;
    currentUseCustomColors = app.UseCustomColors;
    currentBoxSizeX = app.BoxSizeX;
    currentBoxSizeY = app.BoxSizeY;
    currentBoxSizeZ = app.BoxSizeZ;

    % Reset the app state
    resetAppState(app);

    % Restore color and box settings
    app.ChannelColors = currentColors;
    app.UseCustomColors = currentUseCustomColors;
    app.BoxSizeX = currentBoxSizeX;
    app.BoxSizeY = currentBoxSizeY;
    app.BoxSizeZ = currentBoxSizeZ;

    app.clearDBSCANCache(1);  % Clear cache for Channel_1

    % Load the dataset
    [file, path] = uigetfile('*.mat', 'Select a MATLAB data file');
    if isequal(file, 0)
        return;
    end

    fullPath = fullfile(path, file);
    [~, filename, ~] = fileparts(file);

    % Update filename property
    app.filename1 = filename;
    app.filepath1 = path;

    % Load data
    loadedData = load(fullPath);
    app.LoadedData1 = loadedData;

    % Process data
    if isfield(loadedData, 'itr') && isfield(loadedData.itr, 'loc')
        itr = loadedData.itr;
        data = itr.loc(:,10,:) * 1e9;  % Convert to nm
        data = reshape(data, [], 3);

        data(:,2) = 1 * (data(:,2));
        data(:,3) = 1 * (data(:,3) * 0.7);  % Apply z-correction factor

        app.Data1 = data;

        % Load tid data
        if isfield(loadedData, 'tid')
            app.tid1 = loadedData.tid;
        else
            warning('tid data not found in the loaded file');
            app.tid1 = [];
        end

        % Load tim data if available
        if isfield(loadedData, 'tim')
            app.tim1 = loadedData.tim;
        else
            app.tim1 = [];
        end
    else
        errordlg('The loaded file does not contain the expected data structure.', 'Data Structure Error');
        return;
    end

    % Initialize channel structures
    channelName = 'Channel_1';
    app.ChannelFileNames.(channelName) = filename;
    app.ChannelEnableStates.(channelName) = true;  % Channel 1 is always enabled
    app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);

    % Update ChannelListBox
    app.ChannelListBox.Items = {'Channel 1', 'Add Channel'};
    app.ChannelListBox.Value = 'Channel 1';

    % Update channel details
    updateChannelDetails(app, 'Channel 1');

    % Update plots
    updatePlots(app);

    % Ensure 'Add Channel' is in the list
    updateChannelListBox(app);

    app.calculateTraceData();  % Add this line


        end

        % Value changed function: ShowClustersOnlyCheckbox
        function ShowClustersOnlyCheckboxValueChanged(app, event)

            updatePlots(app);

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            plotSelectedClusters(app);
        end

        % Value changed function: EllipsoidFitCheckBox
        function EllipsoidFitCheckBoxValueChanged(app, event)
            plotSelectedClusters(app);
        end

        % Value changed function: ListBox2
        function ListBox_2ValueChanged(app, event)
            Measurement(app);
        end

        % Button pushed function: LoadChannelButton
        function LoadChannelButtonPushed(app, event)
            if nargin < 2
                event = [];
            end

            if isempty(event) || strcmp(app.ChannelListBox.Value, 'New Channel') || strcmp(app.ChannelListBox.Value, 'Add Channel')
                % Find the first empty data slot
                for channelNumber = 1:5
                    if isempty(app.(['Data', num2str(channelNumber)]))
                        break;
                    end
                end

                % If all slots are filled, show an error message
                if channelNumber > 5
                    uialert(app.DBSCAN, 'All channels are already loaded. Please clear a channel before loading new data.', 'Channel Limit Reached');
                    return;
                end

                app.clearDBSCANCache(channelNumber);  % Clear cache for the new channel
                app.loadDataset(channelNumber);

                % Update the ChannelListBox without duplicating entries
                updateChannelListBox(app);

                % Select the newly added channel
                app.ChannelListBox.Value = ['Channel ', num2str(channelNumber)];

                % Ensure the new channel is set to ON
                channelName = ['Channel_', num2str(channelNumber)];
                app.ChannelEnableStates.(channelName) = true;
            else
                % Refresh existing channel
                channelNumber = str2double(regexp(app.ChannelListBox.Value, '\d+', 'match'));
                app.clearDBSCANCache(channelNumber);  % Clear cache for the selected channel
                app.loadDataset(channelNumber);
            end

            % Update channel details
            updateChannelDetails(app, app.ChannelListBox.Value);

            % Ensure correction factors are applied
            channelName = ['Channel_', num2str(channelNumber)];
            if isfield(app.ChannelCorrections, channelName)
                corrections = app.ChannelCorrections.(channelName);
                app.XCorrectionField.Value = corrections.X;
                app.YCorrectionField.Value = corrections.Y;
                app.ZCorrectionField.Value = corrections.Z;

                % Apply corrections to the data
                app.(['Data', num2str(channelNumber), '_off']) = app.(['Data', num2str(channelNumber)]);
                app.(['Data', num2str(channelNumber), '_off'])(:,1) = app.(['Data', num2str(channelNumber), '_off'])(:,1) + corrections.X;
                app.(['Data', num2str(channelNumber), '_off'])(:,2) = app.(['Data', num2str(channelNumber), '_off'])(:,2) + corrections.Y;
                app.(['Data', num2str(channelNumber), '_off'])(:,3) = app.(['Data', num2str(channelNumber), '_off'])(:,3) + corrections.Z;
            end

            % Update visualization
            app.updatePlots2();

            % Update checkbox visibility
            app.updateCheckboxVisibility();

            app.updatePlots2();
            app.calculateTraceData();

        end

        % Value changed function: ChannelEnableCheckbox
        function ChannelEnableCheckboxValueChanged(app, event)
            selectedChannel = app.ChannelListBox.Value;
            channelNumber = str2double(regexp(selectedChannel, '\d+', 'match'));
            channelName = ['Channel_', num2str(channelNumber)];
            app.ChannelEnableStates.(channelName) = event.Value;
            app.updatePlots2();
        end

        % Value changed function: EnableExclusion
        function EnableExclusionValueChanged(app, event)
            updatePlots(app);
            updatePlots2(app);
            app.updateClusterInfo();
            plotSelectedClusters(app)


        end

        % Value changed function: ExclNum
        function ExclNumValueChanged2(app, event)
            if app.ExclNum.Value > 0
                app.EnableExclusion.Value = true;
            end
            updatePlots(app);
            updatePlots2(app);
            app.updateClusterInfo();
            plotSelectedClusters(app);


        end

        % Button pushed function: ShowHeatMap
        function ShowHeatMapPushed(app, event)
            % Close any existing heatmap figures
            if ~isempty(app.figureHandle3D) && isvalid(app.figureHandle3D)
                close(app.figureHandle3D);
            end
            if ~isempty(app.figureHandle2D) && isvalid(app.figureHandle2D)
                close(app.figureHandle2D);
            end

            % Create new heatmap visualizations
            plot3DHeatMap(app, app.allCenteredPoints);
        end

        % Value changed function: XCorrectionField
        function XCorrectionFieldValueChanged(app, event)

            UpdateCorrection(app, 'X', app.XCorrectionField.Value);

        end

        % Value changed function: YCorrectionField
        function YCorrectionFieldValueChanged(app, event)

            UpdateCorrection(app, 'Y', app.XCorrectionField.Value);

        end

        % Value changed function: ZCorrectionField
        function ZCorrectionFieldValueChanged(app, event)

            UpdateCorrection(app, 'Z', app.XCorrectionField.Value);

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Get the currently selected tab
            currentTab = app.TabGroup.SelectedTab;

            % Find all axes in the current tab
            axesObjects = findobj(currentTab, 'Type', 'axes');

            % If no axes found, check if it's the Polar tab
            if isempty(axesObjects) && strcmp(currentTab.Title, 'Polar')
                if ~isempty(app.Panel.Children)
                    axesToSave = app.Panel;
                    defaultFileName = 'Polar_Plot.pdf';
                else
                    msgbox('No graphic to save in the current tab.', 'No Graphic', 'warn');
                    return;
                end
            elseif ~isempty(axesObjects)
                % If multiple axes, let user choose which one to save
                if length(axesObjects) > 1
                    [indx,tf] = listdlg('ListString', arrayfun(@(x) ['Plot ' num2str(x)], 1:length(axesObjects), 'UniformOutput', false), ...
                        'SelectionMode', 'single', ...
                        'PromptString', 'Select a plot to save:');
                    if tf == 0
                        return;  % User cancelled
                    end
                    axesToSave = axesObjects(indx);
                else
                    axesToSave = axesObjects;
                end
                defaultFileName = [currentTab.Title '_Plot.pdf'];
            else
                msgbox('No graphic to save in the current tab.', 'No Graphic', 'warn');
                return;
            end

            % Ask user for save location and filename
            [fileName, pathName] = uiputfile({'*.pdf','PDF Files (*.pdf)'; ...
                '*.eps','EPS Files (*.eps)'}, ...
                'Save Current Graphic As', defaultFileName);
            if isequal(fileName, 0) || isequal(pathName, 0)
                return;  % User cancelled
            end

            fullFilePath = fullfile(pathName, fileName);

            % Save the graphic
            try
                exportgraphics(axesToSave, fullFilePath, 'ContentType', 'vector');
                msgbox(['Graphic saved successfully as ' fullFilePath], 'Save Complete');
            catch e
                errordlg(['Failed to save graphic: ' e.message], 'Save Error');
            end
        end

        % Value changed function: ListBox2_2
        function ListBox2_2ValueChanged(app, event)
            PolarMeasurement(app);
        end

        % Button down function: AcqParametersTab
        function AcqParametersTabButtonDown(app, event)
            if  ~isempty(app.Data1)
                PRM = app.LoadedData1;
                PRM_tid = PRM.tid;
                PRM_tim = PRM.tim;
                PRM_tid = double(PRM_tid)';
                PRM_traces = [app.Data1(:,1),app.Data1(:,2),app.Data1(:,3),PRM_tid(:,1)];
                CFR = PRM.itr.efc ./ PRM.itr.efo;

                PRM_traces_filt = PRM_traces;


                loc_per_trace_threshold = 3;
                % Find unique trace identifiers and their indices in PRM_traces
                [unique_trace_ids, ~, idx] = unique(PRM_traces(:,4));

                % Count the number of occurrences of each trace ID
                trace_counts = accumarray(idx, 1);

                % Find trace IDs that meet the threshold
                valid_trace_ids = unique_trace_ids(trace_counts >= loc_per_trace_threshold);

                % Filter the traces based on the valid trace IDs
                PRM_traces_filt = PRM_traces(ismember(PRM_traces(:,4), valid_trace_ids), :);

                [uv_tid, ~, id_tid] = unique(PRM_traces(:,4));
                n_tid = histcounts(id_tid,"BinWidth",1);

                average_localizations_per_trace = mean(n_tid);
                app.Label_2.Text = ['Average localizations per trace: ', num2str(average_localizations_per_trace)];

                PRM_traces_filt = PRM_traces_filt(ismember(PRM_traces_filt(:,4), uv_tid(n_tid > loc_per_trace_threshold)),:);
                app.PRM_traces_filt = PRM_traces_filt;

                h = histogram(app.UIAxes3_2, id_tid, 'BinWidth', 1);
                counts = h.Values;
                max_counts = max(counts);
                new_ylim =max_counts+0.1*max_counts;
                xlabel(app.UIAxes3_2, "Trace ID");
                ylabel(app.UIAxes3_2, "Counts");
                title(app.UIAxes3_2, 'Counts per Trace ID');
                ylim(app.UIAxes3_2, [0 new_ylim]);  % This sets the lower limit of the y-axis to 0 and the upper to automatic
                xlim(app.UIAxes3_2, [0 inf]);

                % Normalize timestamps so the minimum is zero
                PRM_tim_norm = PRM_tim - min(PRM_tim);

                % Generate bin edges from start to end, one minute apart
                bin_edges = 0:60:max(PRM_tim_norm) + 60;  % Ensure it starts at 0 and covers the last bin

                % Calculate histogram counts
                [counts, edges] = histcounts(PRM_tim_norm, bin_edges);

                % Plotting the histogram
                bar(app.UIAxes3, edges(1:end-1)/60, counts, 'histc');  % Use 'histc' to ensure alignment with edges
                xlabel(app.UIAxes3, 'Time (minutes)');
                ylabel(app.UIAxes3, 'Number of Localizations');
                title(app.UIAxes3, 'Localizations Per Minute');
                xticks(app.UIAxes3, edges(1:5:end)/60);  % Set ticks at every 5 bins, converted to minutes
                % Adjust visual settings if necessary
                set(app.UIAxes3, 'FontSize', 12);  % Enhance font size for readability
            else
            end

        end

        % Value changed function: DBSCANCheckBox
        function DBSCANCheckBoxValueChanged(app, event)

            updatePlots2(app);

        end

        % Value changed function: ColorCheck
        function ColorCheckValueChanged(app, event)

            PolarMeasurement(app);

        end

        % Callback function
        function LoadBeadDataButtonPushed(app, event)
            if isempty(app.Data1)
    uialert(app.DBSCAN, 'Please load your primary data first.', 'Data Missing');
    return;
end
            [file, path] = uigetfile('*.mat', 'Select a MATLAB data file');
    if isequal(file, 0)
        app.ZarrLabel.Text = 'No file selected';
        return;
    end
    fullPath = fullfile(path, file);
    processBeadDataFile(app, fullPath);

        end

        % Button down function: FieldofViewMultiTab
        function FieldofViewMultiTabButtonDown(app, event)
            % Check if there's any data loaded
            if isempty(app.Data1)
                % Display a message to the user
                uialert(app.DBSCAN, 'Please load data for Channel 1 first.', 'No Data Loaded');
                return;
            end

            % Initialize the channel list if it's empty
            if isempty(app.ChannelListBox.Items)
                app.ChannelListBox.Items = {'Channel 1', 'Add Channel'};
                updateChannelDetails(app, 'Channel 1');
            end

            % Update the plots
            app.updatePlots2();


            % Update checkbox visibility
            app.updateCheckboxVisibility();

        end

        % Value changed function: ReferenceStainingDropDown
        function ReferenceStainingDropDownValueChanged(app, event)
            app.calculateCorrections();
            app.updateMulticolorTabFromCorrections();
            app.updateAlignment();
            app.visualizeBeadLocalizations();

        end

        % Button pushed function: ExportasmattifButton
        function ExportasmattifButtonPushed(app, event)
            ExportButtonPushed(app);

        end

        % Button down function: LocalizationPrecisionTab
        function LocalizationPrecisionTabButtonDown(app, event)
            % Check if there's any data loaded
            if isempty(app.Data1)
                % Display a message to the user
                uialert(app.DBSCAN, 'Please load data for Channel 1 first.', 'No Data Loaded');
                return;
            end

            app.updateLocalizationPrecisionTab();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.PolarMeasurement(app);

        end

        % Selection changed function: ButtonGroup1
        function ButtonGroup1SelectionChanged(app, event)
            app.Measurement();
        end

        % Button down function: NumbersTab
        function NumbersTabButtonDown(app, event)
            app.updateClusterInfo();

        end

        % Callback function
        function RDFButtonPushed(app, event)
            ShowRDFButtonPushed(app);
        end

        % Button down function: NumbersmultiTab
        function NumbersmultiTabButtonDown(app, event)
            updateClusterInfo(app);
            updateMultiChannelClusterInfo(app);

        end

        % Value changed function: NormalizeIndividuallyCheckBox
        function NormalizeIndividuallyCheckBoxValueChanged(app, event)
            Measurement(app);
        end

        % Button pushed function: SaveLocPrecPDFButton
        function SaveLocPrecPDFButtonPushed2(app, event)
            % Get all axes in the LocPrecTab
            axesHandles = findobj(app.LocalizationPrecisionTab, 'Type', 'axes');

            if isempty(axesHandles)
                uialert(app.DBSCAN, 'No plots to save.', 'No Data');
                return;
            end

            % Ask user for save location
            saveDir = uigetdir(pwd, 'Select Directory to Save PNG Files');
            if isequal(saveDir, 0)
                return;
            end

            % Loop through each axes and save as PNG
            for i = 1:length(axesHandles)
                ax = axesHandles(i);

                % Create a new figure with a specific size
                fig = figure('Visible', 'off', 'Units', 'pixels', 'Position', [0, 0, 600, 450]);

                % Copy the axes to the new figure and adjust its position
                newAx = copyobj(ax, fig);
                set(newAx, 'Units', 'normalized', 'Position', [0.1, 0.1, 0.8, 0.8]);

                % Ensure all elements are within the axes
                axis(newAx, 'tight');

                % Get the title of the plot
                titleObj = get(newAx, 'Title');
                titleText = get(titleObj, 'String');
                if iscell(titleText)
                    titleText = strjoin(titleText, '_');
                end

                % Remove any characters that are not suitable for filenames
                titleText = regexprep(titleText, '[^\w\d]+', '_');

                % Create filename
                filename = fullfile(saveDir, sprintf('LocPrec_Plot_%d_%s.png', i, titleText));

                % Save the figure as PNG
                print(fig, filename, '-dpng', '-r300');  % 300 dpi resolution

                % Close the temporary figure
                close(fig);
            end

            % Inform user that saving is complete
            uialert(app.DBSCAN, sprintf('Localization precision plots have been saved as PNG files in:\n%s', saveDir), 'Save Complete', 'Icon', 'success');

        end

        % Button pushed function: SaveNumMultCSVButton
        function SaveNumMultCSVButtonPushed(app, event)
            % Check if the table has data
            if isempty(app.UITableMulti.Data)
                uialert(app.DBSCAN, 'No data to save.', 'Empty Table');
                return;
            end

            % Ask user for save location
            [fileName, pathName] = uiputfile('*.csv', 'Save NumMult Table as CSV');
            if isequal(fileName, 0) || isequal(pathName, 0)
                return;
            end

            fullPath = fullfile(pathName, fileName);

            % Get table data and column names
            data = app.UITableMulti.Data;
            columnNames = app.UITableMulti.ColumnName;

            % Create a table with column names
            T = array2table(data, 'VariableNames', columnNames);

            % Write the table to a CSV file
            writetable(T, fullPath);

            % Inform user that saving is complete
            uialert(app.DBSCAN, sprintf('NumMult table has been saved as CSV:\n%s', fullPath), 'Save Complete', 'Icon', 'success');

        end

        % Value changed function: AlphaShapeCheckBox
        function AlphaShapeCheckBoxValueChanged(app, event)
            app.AlphaRadiusSlider.Visible = app.AlphaShapeCheckBox.Value;
            app.AlphaRadiusLabel.Visible = app.AlphaShapeCheckBox.Value;
            plotSelectedClusters(app);
        end

        % Value changed function: AlphaRadiusSlider
        function AlphaRadiusSliderValueChanged(app, event)
            app.AlphaRadiusLabel.Text = sprintf('Alpha Radius: %.2f', event.Value);
            plotSelectedClusters(app);
        end

        % Callback function
        function Button_2Pushed(app, event)
            averageParticles(app);

        end

        % Value changed function: ChannelListBox
        function ChannelListBoxValueChanged(app, event)
            selectedChannel = app.ChannelListBox.Value;
            if strcmp(selectedChannel, 'New Channel') || strcmp(selectedChannel, 'Add Channel')
                % Trigger the load function
                app.LoadChannelButtonPushed();
            else
                updateChannelDetails(app, selectedChannel);
            end
        end

        % Menu selected function: ColorsMenu
        function ColorsMenuSelected(app, event)
    % Create a new figure for color preferences
    colorFig = uifigure('Name', 'Channel Color Preferences', 'Position', [100 100 300 380]);
    
    % -------------------------------
    % Colormap selection section
    % -------------------------------
    % Define a list of available colormaps
    colormapList = {'parula','jet','hsv','hot','cool','spring','summer','autumn','winter','gray'};
    % Add a label for the dropdown
    uilabel(colorFig, 'Text', 'Select Colormap:', 'Position', [20 340 150 20]);
    % Add a dropdown for colormap selection; set its default value to the current colormap
    colormapDropdown = uidropdown(colorFig, 'Items', colormapList, ...
                                   'Position', [180 340 100 22], 'Value', app.currentcolormap);
    % When the user changes the selection, call updateColormap
    colormapDropdown.ValueChangedFcn = @(src, event) updateColormap(app, src.Value);
    
    % -------------------------------
    % Channel color selection section
    % -------------------------------
    % Define channel names and initialize buttons array
    channelNames = {'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'Channel 5'};
    app.ChannelColorButtons = cell(1, 5);
    % Create a button for each channel for custom color selection
    for i = 1:5
        labelY = 300 - (i-1)*40;  % Adjust vertical positioning
        uilabel(colorFig, 'Text', [channelNames{i} ' Color:'], 'Position', [20 labelY 150 20]);
        app.ChannelColorButtons{i} = uibutton(colorFig, 'push', 'Text', 'Select', ...
            'Position', [180 labelY 100 20], 'ButtonPushedFcn', @(btn,event) selectColor(app, ['Channel' num2str(i)]));
        app.ChannelColorButtons{i}.BackgroundColor = app.ChannelColors{i};
    end
    
    % -------------------------------
    % Apply and Reset buttons
    % -------------------------------
    % Add an Apply button to update colors in your plots
    uibutton(colorFig, 'push', 'Text', 'Apply', 'Position', [50 20 100 30], ...
             'ButtonPushedFcn', @(btn,event) applyColors(app));
    % Add a Reset button to revert to default colors
    uibutton(colorFig, 'push', 'Text', 'Reset to Default', 'Position', [160 20 120 30], ...
             'ButtonPushedFcn', @(btn,event) resetColors(app));

        end

        % Menu selected function: CustomSizeMenu
        function CustomSizeMenuSelected(app, event)
            defaultSize = sprintf('%dx%dx%d', app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ);
            newSize = inputdlg('Enter custom size in nm (XxYxZ):', 'Custom Size', 1, {defaultSize});
            if ~isempty(newSize)
                sizes = sscanf(newSize{1}, '%dx%dx%d');
                if length(sizes) == 3 && all(sizes > 0)
                    app.BoxSizeX = sizes(1);
                    app.BoxSizeY = sizes(2);
                    app.BoxSizeZ = sizes(3);
                    app.plotSelectedClusters();
                    app.plotSelectedClusters2();
                    app.updateMultiChannelClusterInfo

                else
                    errordlg('Please enter a valid size in the format XxYxZ (e.g., 500x500x500).', 'Invalid Input');
                end
            end
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged2(app, event)
            plotSelectedClusters2(app);

        end

        % Value changed function: EllipsoidFitCheckBox_2
        function EllipsoidFitCheckBox_2ValueChanged(app, event)
            plotSelectedClusters2(app);

        end

        % Value changed function: AlphaShapeCheckBox_2
        function AlphaShapeCheckBox_2ValueChanged(app, event)
            value = app.AlphaShapeCheckBox_2.Value;
            app.AlphaRadiusSlider_2.Visible = app.AlphaShapeCheckBox.Value;
            app.AlphaRadiusLabel_2.Visible = app.AlphaShapeCheckBox.Value;
            plotSelectedClusters2(app);
        end

        % Button down function: ClusterAnalysisMultiTab
        function ClusterAnalysisMultiTabButtonDown(app, event)
            
           % Check if there's any data loaded
            if isempty(app.Data1)
                % Display a message to the user
                uialert(app.DBSCAN, 'Please load data for Channel 1 first.', 'No Data Loaded');
                return;
            end
            plotSelectedClusters2(app);
            updateCheckboxVisibility(app);
        end

        % Value changed function: Ch5CheckBox
        function Ch5CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);

        end

        % Value changed function: Ch4CheckBox
        function Ch4CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);

        end

        % Value changed function: Ch3CheckBox
        function Ch3CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);

        end

        % Value changed function: Ch2CheckBox
        function Ch2CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);

        end

        % Callback function
        function Ch1CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);

        end

        % Callback function
        function BoxSizeMenuSelected(app, event)
              % Get current box size
    currentSize = sprintf('%dx%dx%d', app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ);
    
    % Prompt user for new box size
    newSize = inputdlg('Enter analysis box size in nm (XxYxZ):', 'Set Analysis Box Size', 1, {currentSize});
    
    if ~isempty(newSize)
        sizes = sscanf(newSize{1}, '%dx%dx%d');
        if length(sizes) == 3 && all(sizes > 0)
            app.BoxSizeX = sizes(1);
            app.BoxSizeY = sizes(2);
            app.BoxSizeZ = sizes(3);
            
            % Update the multi-channel analysis
            app.updateMultiChannelClusterInfo();
            
            % Optionally, update any visualizations that use the box size
            app.updatePlots2();
        else
            errordlg('Please enter a valid size in the format XxYxZ (e.g., 500x500x500).', 'Invalid Input');
        end
    end
        end

        % Button pushed function: LoadBeadDataButton
        function Zarr_extractPushed(app, event)
    if isempty(app.Data1)
        uialert(app.DBSCAN, 'Please load your primary data first.', 'Data Missing');
        return;
    end

    % Ask user for the folder containing zarr data
    zarrFolder = uigetdir(pwd, 'Select the folder containing Zarr data');
    if isequal(zarrFolder, 0)
        uialert(app.DBSCAN, 'No folder selected for Zarr data.', 'Selection Cancelled');
        return;
    end
    
    % Check if the selected folder has the expected Zarr structure
    if ~app.isValidZarrFolder(zarrFolder)
    uialert(app.DBSCAN, ['The selected folder does not appear to be a valid Zarr dataset.' ...
            newline newline 'Expected structure: A parent folder containing one or more staining folders,' ...
            ' each containing one or more bead folders with .zattrs and .zarray files.'], ...
            'Invalid Zarr Structure');
        return;
    end

    % Create a temporary directory in the user's documents folder
    tempDir = fullfile(userpath, 'matFLUX_Data');
    if ~exist(tempDir, 'dir')
        [status, msg] = mkdir(tempDir);
        if ~status
            uialert(app.DBSCAN, ['Could not create directory: ' msg], 'Directory Error');
            return;
        end
    end
    
    % Specify the full path for the output file
    outputFile = fullfile(tempDir, 'correction.mat');
    
    d = uiprogressdlg(app.DBSCAN, 'Title', 'Extracting Zarr Data', ...
        'Message', 'Running zarr_extract.py, please wait...', 'Indeterminate', 'on');
    try
        % Determine the Python executable (platform-specific)
        if ispc  % Windows
            [~, pythonPath] = system('where python');
        else    % macOS/Linux
            [~, pythonPath] = system('which python'); 
            if contains(pythonPath, 'not found')
                [~, pythonPath] = system('which python3');
            end
        end
        pythonPath = strtrim(pythonPath);
        if isempty(pythonPath) || contains(pythonPath, 'not found')
            uialert(app.DBSCAN, 'Python executable not found. Please ensure Python is installed.', 'Python Not Found');
            close(d);
            return;
        end

        % Get the path to the resources folder
        if isdeployed
            % For deployed apps
            if ispc  % Windows
                appRoot = fileparts(ctfroot);
                zarrScript = fullfile(appRoot, 'resources', 'zarr_extract.py');
            elseif ismac  % macOS
                appDir = '/Applications/matFLUX/application/matFLUX.app/Contents/resources/matflux_mcr/matflux/matflux/resources';
                zarrScript = fullfile(appDir, 'zarr_extract.py');
            else  % Linux
                % In Linux, compiled MATLAB apps typically have resources in a different location
                appRoot = ctfroot;
                zarrScript = fullfile(appRoot, 'resources', 'zarr_extract.py');
                
                % If not found, try alternative Linux installation path
                if ~exist(zarrScript, 'file')
                    % Common Linux installation paths to check
                    possibleLinuxPaths = {
                        fullfile('/usr/local/matFLUX/resources', 'zarr_extract.py'),
                        fullfile('/opt/matFLUX/resources', 'zarr_extract.py'),
                        fullfile(getenv('HOME'), 'matFLUX/resources', 'zarr_extract.py')
                    };
                    
                    for i = 1:length(possibleLinuxPaths)
                        if exist(possibleLinuxPaths{i}, 'file')
                            zarrScript = possibleLinuxPaths{i};
                            break;
                        end
                    end
                end
            end
            
            % Verify the script exists and try alternative locations if needed
            if ~exist(zarrScript, 'file')
                % Common fallback paths across all platforms
                possiblePaths = {
                    fullfile(ctfroot, 'resources', 'zarr_extract.py'),
                    fullfile(fileparts(mfilename('fullpath')), 'resources', 'zarr_extract.py'),
                    fullfile(pwd, 'resources', 'zarr_extract.py')
                };
                
                % For Linux/Mac, also check some system directories
                if ~ispc
                    possiblePaths{end+1} = fullfile('/usr/share/matFLUX/resources', 'zarr_extract.py');
                    possiblePaths{end+1} = fullfile('/usr/local/share/matFLUX/resources', 'zarr_extract.py');
                end
                
                scriptFound = false;
                for i = 1:length(possiblePaths)
                    if exist(possiblePaths{i}, 'file')
                        zarrScript = possiblePaths{i};
                        scriptFound = true;
                        break;
                    end
                end
                
                if ~scriptFound
                    uialert(app.DBSCAN, 'Python script zarr_extract.py not found. Please check your installation.', 'Script Not Found');
                    disp('Searched the following locations:');
                    disp(['Original path: ' zarrScript]);
                    for i = 1:length(possiblePaths)
                        disp(['Alternative path ' num2str(i) ': ' possiblePaths{i}]);
                    end
                    close(d);
                    return;
                end
            end
        else
            % For development in MATLAB
            appDir = fileparts(mfilename('fullpath'));
            zarrScript = fullfile(appDir, 'resources', 'zarr_extract.py');
            
            % If running from a different directory than the script location
            if ~exist(zarrScript, 'file')
                % Try to find the script in common development locations
                devPaths = {
                    fullfile(pwd, 'resources', 'zarr_extract.py'),
                    fullfile(fileparts(which('matFLUX')), 'resources', 'zarr_extract.py')
                };
                
                for i = 1:length(devPaths)
                    if exist(devPaths{i}, 'file')
                        zarrScript = devPaths{i};
                        break;
                    end
                end
            end
        end

        % Add logging to help with debugging
        disp(['Using zarr_extract.py script from: ' zarrScript]);
        
        % Run the external Python script, passing both the input and output paths
        cmd = ['"' pythonPath '" "', zarrScript, '" "', zarrFolder, '" "', outputFile, '"'];
        disp(['Running command: ' cmd]); % For debugging
        [status, cmdout] = system(cmd);
        
        if status ~= 0
            uialert(app.DBSCAN, ['Error running zarr_extract.py:' newline, cmdout], 'Execution Error');
            close(d);
            return;
        end

        % After successful execution, process the generated file
        if isfile(outputFile)
            processBeadDataFile(app, outputFile);
            uialert(app.DBSCAN, 'Correction data loaded successfully.', 'Success', 'Icon', 'info');
        else
            uialert(app.DBSCAN, ['Correction file not found at: ' outputFile newline 'Output from script:' newline cmdout], 'File Not Found');
        end
    catch ME
        uialert(app.DBSCAN, ['An error occurred:' newline, ME.message], 'Error');
        disp(['Error details: ' getReport(ME)]);
    end
    close(d);


        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
    aboutText = sprintf(['matFLUX 1.0\n' ...
                         'Developed by Moritz Hacke\n' ...
                         'This application processes MINFLUX localization data and performs alignment, clustering, and visualization.\n' ...
                         'Please visit: matflux.mhke.de']);
    % Display a uialert with an info icon
    uialert(app.DBSCAN, aboutText, 'About matFLUX 1.0', 'Icon', 'info');

        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
    % Create a basic help window using standard figure
    helpFig = figure('Name', 'Help - matFLUX 1.0', 'Position', [200 200 600 500], ...
                     'NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none');
    
    % Combine all the help text into one FAQ-style content
    helpText = sprintf([...
    'matFLUX 1.0 - Help Menu\n\n', ...
    'OVERVIEW:\n', ...
    'matFLUX is a MATLAB-based application for processing MINFLUX localization data. ', ...
    'It integrates advanced clustering (using DBSCAN), alignment, multi-channel visualization, ', ...
    'and quantitative analysis to help you extract spatial and temporal information from super-resolution microscopy datasets.\n\n', ...
    'SYSTEM REQUIREMENTS:\n', ...
    '- MATLAB (with App Designer support).\n', ...
    '- Statistics and Machine Learning Toolbox (for DBSCAN clustering).\n', ...
    '- Python installed (for optional Zarr data extraction).\n', ...
    '- MINFLUX .mat files containing required fields (e.g., ''itr.loc'', ''tid'', ''tim'').\n\n', ...
    'GETTING STARTED:\n', ...
    '1. Launch the application. A welcome dialog will guide you through the basic workflow.\n', ...
    '2. Click the "Load/New" button to load your primary MINFLUX .mat file. Ensure that your file contains the expected structure.\n', ...
    '3. Once loaded, your primary data will appear in the "Field of View" tab.\n', ...
    '4. Use the "Load Channel" button to add additional channels for multi-channel analysis.\n\n', ...
    'DATA LOADING & CHANNEL MANAGEMENT:\n', ...
    '- Primary Channel: Loaded via "Load/New" (contains the main localization data).\n', ...
    '- Additional Channels: Load using "Load Channel". Each channel can be individually enabled/disabled and corrected for drift.\n\n', ...
    'ANALYSIS & VISUALIZATION PANELS:\n', ...
    '• Field of View Tab: Displays the overall localization field after DBSCAN clustering. ', ...
    'Use the "Show Noise" toggle to filter out noise points.\n', ...
    '• Single Cluster Analysis Tab: Focus on individual clusters. Options include fitting alpha shapes and ellipsoids for spatial characterization.\n', ...
    '• Multi-Channel Overview Tab: Align and compare data from multiple channels. Adjust x, y, and z shifts as needed.\n', ...
    '• Multi Cluster Analysis Tab: Compare clusters across channels and generate statistical summaries.\n', ...
    '• Line Profile & Polar Plot Tabs: Generate distance histograms, line profiles, and polar scatter plots to study radial distributions from cluster centroids.\n', ...
    '• Numbers & Quality Control Tabs: View summary tables of cluster statistics (e.g., volume, surface area, trace counts) and assess data quality over time.\n', ...
    '• Localization Precision Tab: Visualize histograms and error estimates for localization precision from your data.\n\n', ...
    'CLUSTERING & PARAMETER ADJUSTMENT:\n', ...
    '- Adjust DBSCAN parameters (Epsilon and MinPts) using the provided spinners. ', ...
    'Activate the exclusion option to filter out clusters with too few points.\n\n', ...
    'ALIGNMENT & CORRECTIONS:\n', ...
    '- Load bead data with the "Load Bead Data" button to perform drift correction.\n', ...
    '- Select a reference staining in the dropdown menu, and matFLUX will calculate alignment corrections for additional channels.\n', ...
    '- Correction factors (x, y, z) can be manually fine-tuned in the Multi-Channel Overview tab.\n\n', ...
    'EXPORTING DATA:\n', ...
    '- Use the "Export as .mat/.tif" button to save your processed data. ', ...
    'Choose to export corrected data as MATLAB files or multi-channel images as TIFF files.\n\n', ...
    'CUSTOMIZATION:\n', ...
    '- Colors: Change channel colors via the "Colors" menu. A dedicated color preferences window allows custom selection and resetting to defaults.\n', ...
    '- Box Size: Adjust the analysis region dimensions via the "Size" menu (format: XxYxZ in nm).\n', ...
    '- Colormap: Select a different colormap for your plots using the dropdown in the color preferences.\n\n', ...
    'TROUBLESHOOTING & FAQ:\n', ...
    'Q: What should I do if my data file does not load?\n', ...
    'A: Verify that the .mat file contains the required fields (e.g., ''itr.loc'', ''tid'', ''tim''). Ensure the file follows the expected structure.\n\n', ...
    'Q: How do I adjust the clustering parameters?\n', ...
    'A: Use the Epsilon and MinPts spinners to change the DBSCAN settings. Use the exclusion option to remove clusters with insufficient points.\n\n', ...
    'Q: My alignment seems off. What can I do?\n', ...
    'A: Make sure you have loaded the bead data correctly and selected the appropriate reference channel. Check that the bead data contains valid localization and timing information.\n\n', ...
    'Q: Where can I get further support or report issues?\n', ...
    'A: Visit our website or contact the developer using the information below.\n\n', ...
    'CONTACT & SUPPORT:\n', ...
    'Developer: Moritz Hacke\n', ...
    'Website: https://matflux.mhke.de/\n', ...
    'Email: support@matflux.mhke.de\n\n', ...
    'DISCLAIMER:\n', ...
    'This software is provided as-is without warranties. Please back up your data before processing. Use at your own risk.\n\n', ...
    'Version: 1.0\n' ...
    ]);

    
    % Create a scrollable text area to display all the help content
    helpTextArea = uicontrol(helpFig, 'Style', 'edit', 'String', helpText, ...
                  'Position', [20 60 560 420], 'FontSize', 11, ...
                  'HorizontalAlignment', 'left', 'Max', 50, ...
                  'Min', 0, 'Enable', 'inactive', ...
                  'BackgroundColor', [1 1 1]);
    
    % Add a button to open the website
    uicontrol(helpFig, 'Style', 'pushbutton', 'String', 'Open Online Documentation', ...
              'Position', [20 20 200 30], ...
              'Callback', @(btn,event) web('https://matflux.mhke.de/', '-browser'));
    
    % Add a close button
    uicontrol(helpFig, 'Style', 'pushbutton', 'String', 'Close', ...
              'Position', [490 20 90 30], ...
              'Callback', @(btn,event) close(helpFig));

        end

        % Menu selected function: ScriptLocation
        function ScriptLocationMenuSelected(app, event)
    % Let user choose the Python script location
    [file, path] = uigetfile({'*.py', 'Python Files (*.py)'}, 'Select zarr_extract.py Script');
    
    if isequal(file, 0) || isequal(path, 0)
        % User cancelled
        return;
    end
    
    fullPath = fullfile(path, file);
    
    % Verify that it's the correct file
    if ~strcmp(file, 'zarr_extract.py')
        response = questdlg(['Selected file is "' file '" not "zarr_extract.py". Do you want to use it anyway?'], ...
                           'Script Verification', 'Yes', 'No', 'No');
        if strcmp(response, 'No')
            return;
        end
    end
    
    % Store the path as an application property
    if ~isprop(app, 'CustomZarrScriptPath')
        % Dynamically add the property if it doesn't exist
        addprop(app, 'CustomZarrScriptPath');
    end
    app.CustomZarrScriptPath = fullPath;
    
    % Provide feedback to the user
    msg = ['zarr_extract.py script location set to:' newline fullPath];
    uialert(app.DBSCAN, msg, 'Script Location Updated', 'Icon', 'success');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DBSCAN = uifigure('Visible', 'off');
            app.DBSCAN.Name = 'matFLUX 1.0';
            app.SettingsMenu = uimenu(app.DBSCAN);
            app.SettingsMenu.Text = 'Settings';
            app.ColorsMenu = uimenu(app.SettingsMenu);
            app.ColorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorsMenuSelected, true);
            app.ColorsMenu.Text = 'Colors';
            app.CustomSizeMenu = uimenu(app.SettingsMenu);
            app.CustomSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomSizeMenuSelected, true);
            app.CustomSizeMenu.Text = 'Size';
            app.ScriptLocation = uimenu(app.SettingsMenu);
            app.ScriptLocation.MenuSelectedFcn = createCallbackFcn(app, @ScriptLocationMenuSelected, true);
            app.ScriptLocation.Text = 'Script Location';
            app.HelpMenu = uimenu(app.DBSCAN);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Tooltip = {'Help Menu'};
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.DBSCAN);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Tooltip = {'Pop-up About'};
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.DBSCAN);
            title(app.UIAxes, 'Cluster Overview')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8];
            app.UIAxes.YScale = 'log';
            app.UIAxes.ZTick = [];
            app.LoadNewButton = uibutton(app.DBSCAN, 'push');
            app.LoadNewButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewButtonPushed, true);
            app.LoadNewButton.Tooltip = {'Click here to Load your first MINFLUX .mat file'};
            app.LoadNewButton.Text = 'Load/New';
            app.EpsilonLabel = uilabel(app.DBSCAN);
            app.EpsilonLabel.Text = 'Epsilon';
            app.EpsilonSpinner = uispinner(app.DBSCAN);
            app.EpsilonSpinner.Step = 0.5;
            app.EpsilonSpinner.ValueChangedFcn = createCallbackFcn(app, @EpsilonSpinnerValueChanged, true);
            app.EpsilonSpinner.Tooltip = {'Adjust DBSCAN Epsilon Value'};
            app.EpsilonSpinner.Value = 45;
            app.MinPtsLabel = uilabel(app.DBSCAN);
            app.MinPtsLabel.Text = 'MinPts';
            app.MinPtsSpinner = uispinner(app.DBSCAN);
            app.MinPtsSpinner.ValueChangedFcn = createCallbackFcn(app, @MinPtsSpinnerValueChanged, true);
            app.MinPtsSpinner.Tooltip = {'DBSCAN MinPts Value'};
            app.MinPtsSpinner.Value = 5;
            app.FileNameLabel1 = uilabel(app.DBSCAN);
            app.FileNameLabel1.Text = '';
            app.TabGroup = uitabgroup(app.DBSCAN);
            app.FieldofViewTab = uitab(app.TabGroup);
            app.FieldofViewTab.Tooltip = {'FIELD OF FIEW after DBSCAN. Adjust parameters to refine results.'};
            app.FieldofViewTab.Title = 'Field of View';
            app.UIAxes2 = uiaxes(app.FieldofViewTab);
            app.ShowClustersOnlyCheckbox = uibutton(app.FieldofViewTab, 'state');
            app.ShowClustersOnlyCheckbox.ValueChangedFcn = createCallbackFcn(app, @ShowClustersOnlyCheckboxValueChanged, true);
            app.ShowClustersOnlyCheckbox.Text = {'Remove'; 'Noise'};
            app.DataFieldofViewforMainChannelLabel = uilabel(app.FieldofViewTab);
            app.DataFieldofViewforMainChannelLabel.Text = 'Data Field of View for Main Channel';
            app.SingleClusterAnalysisTab = uitab(app.TabGroup);
            app.SingleClusterAnalysisTab.Tooltip = {'Inspect and analyze SINGLE CLUSTERS from one channel. Optionally fit ellipsoids and view heatmaps.'};
            app.SingleClusterAnalysisTab.Title = 'Single Cluster Analysis';
            app.UIAxes5 = uiaxes(app.SingleClusterAnalysisTab);
            app.ListBox = uilistbox(app.SingleClusterAnalysisTab);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.EllipsoidFitCheckBox = uicheckbox(app.SingleClusterAnalysisTab);
            app.EllipsoidFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @EllipsoidFitCheckBoxValueChanged, true);
            app.EllipsoidFitCheckBox.Tooltip = {'Fits Ellipsoid over the Point Cloud'};
            app.EllipsoidFitCheckBox.Text = 'Ellipsoid Fit';
            app.ShowHeatMap = uibutton(app.SingleClusterAnalysisTab, 'push');
            app.ShowHeatMap.ButtonPushedFcn = createCallbackFcn(app, @ShowHeatMapPushed, true);
            app.ShowHeatMap.Text = 'Heatmap';
            app.AlphaShapeCheckBox = uicheckbox(app.SingleClusterAnalysisTab);
            app.AlphaShapeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlphaShapeCheckBoxValueChanged, true);
            app.AlphaShapeCheckBox.Tooltip = {'Fits alphsShape over Point Cloud'};
            app.AlphaShapeCheckBox.Text = 'Alpha Shape';
            app.AlphaRadiusSlider = uislider(app.SingleClusterAnalysisTab);
            app.AlphaRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaRadiusSliderValueChanged, true);
            app.AlphaRadiusSlider.Visible = 'off';
            app.AlphaRadiusSlider.Value = 50;
            app.AlphaRadiusLabel = uilabel(app.SingleClusterAnalysisTab);
            app.AlphaRadiusLabel.Visible = 'off';
            app.AlphaRadiusLabel.Text = '';
            app.SingleAnalysisofClustersfromMainChannelLabel = uilabel(app.SingleClusterAnalysisTab);
            app.SingleAnalysisofClustersfromMainChannelLabel.Text = 'Single Analysis of Clusters from Main Channel';
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Tooltip = {'Automatically align data and correct drift using bead correction data (from MINFLUX system).'};
            app.AlignmentTab.Title = 'Alignment';
            app.LocalizationAxes = uiaxes(app.AlignmentTab);
            title(app.LocalizationAxes, 'Title')
            xlabel(app.LocalizationAxes, 'X')
            ylabel(app.LocalizationAxes, 'Y')
            zlabel(app.LocalizationAxes, 'Z')
            app.DeviationAxes = uiaxes(app.AlignmentTab);
            title(app.DeviationAxes, 'Title')
            xlabel(app.DeviationAxes, 'X')
            ylabel(app.DeviationAxes, 'Y')
            zlabel(app.DeviationAxes, 'Z')
            app.LoadBeadDataButton = uibutton(app.AlignmentTab, 'push');
            app.LoadBeadDataButton.ButtonPushedFcn = createCallbackFcn(app, @Zarr_extractPushed, true);
            app.LoadBeadDataButton.Tooltip = {'Select the Folder containing Zarr Files - The Data is then processed, loaded into the app, and stored as correction.mat'};
            app.LoadBeadDataButton.Text = 'Load Bead Data';
            app.ReferenceStainingDropDownLabel = uilabel(app.AlignmentTab);
            app.ReferenceStainingDropDownLabel.Text = 'ReferenceStaining';
            app.ReferenceStainingDropDown = uidropdown(app.AlignmentTab);
            app.ReferenceStainingDropDown.Items = {'Channel 1'};
            app.ReferenceStainingDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceStainingDropDownValueChanged, true);
            app.ReferenceStainingDropDown.Tooltip = {'Select the Reference Channel for the multi-channel Alignment'};
            app.ReferenceStainingDropDown.Value = 'Channel 1';
            app.AlignmentandCorrectionFactorLabel = uilabel(app.AlignmentTab);
            app.AlignmentandCorrectionFactorLabel.Text = 'Alignment and Correction Factor';
            app.ZarrLabel = uilabel(app.AlignmentTab);
            app.ZarrLabel.Text = '';
            app.SelecttheParentFolderofZarrFileFoldersLabel = uilabel(app.AlignmentTab);
            app.SelecttheParentFolderofZarrFileFoldersLabel.Text = 'Select the Parent Folder of Zarr File Folders';
            app.FieldofViewMultiTab = uitab(app.TabGroup);
            app.FieldofViewMultiTab.Tooltip = {'View and adjust MULTI-CHANNEL data. Apply x/y/z shifts and color corrections as needed.'};
            app.FieldofViewMultiTab.Title = 'Field of View (Multi)';
            app.FieldofViewMultiTab.ButtonDownFcn = createCallbackFcn(app, @FieldofViewMultiTabButtonDown, true);
            app.UIAxes7 = uiaxes(app.FieldofViewMultiTab);
            app.ExportasmattifButton = uibutton(app.FieldofViewMultiTab, 'push');
            app.ExportasmattifButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasmattifButtonPushed, true);
            app.ExportasmattifButton.Tooltip = {'Export the corrected multi-channel file as .mat data or .tif image file'};
            app.ExportasmattifButton.Text = 'Export as .mat/.tif';
            app.ChannelListBox = uilistbox(app.FieldofViewMultiTab);
            app.ChannelListBox.Items = {'Channel 1', 'Add Channel'};
            app.ChannelListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListBoxValueChanged, true);
            app.ChannelListBox.Tooltip = {'Select Channel for further adjustments'};
            app.ChannelListBox.Value = 'Channel 1';
            app.ChannelDetailsPanel = uipanel(app.FieldofViewMultiTab);
            app.ZCorrectionField = uieditfield(app.ChannelDetailsPanel, 'numeric');
            app.ZCorrectionField.ValueChangedFcn = createCallbackFcn(app, @ZCorrectionFieldValueChanged, true);
            app.ZCorrectionField.Tooltip = {'Add Shift manually'};
            app.YCorrectionField = uieditfield(app.ChannelDetailsPanel, 'numeric');
            app.YCorrectionField.ValueChangedFcn = createCallbackFcn(app, @YCorrectionFieldValueChanged, true);
            app.YCorrectionField.Tooltip = {'Add Shift manually'};
            app.XCorrectionField = uieditfield(app.ChannelDetailsPanel, 'numeric');
            app.XCorrectionField.ValueChangedFcn = createCallbackFcn(app, @XCorrectionFieldValueChanged, true);
            app.XCorrectionField.Tooltip = {'Add Shift manually'};
            app.xLabel = uilabel(app.ChannelDetailsPanel);
            app.xLabel.Tooltip = {'Add Shift manually'};
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.ChannelDetailsPanel);
            app.yLabel.Tooltip = {'Add Shift manually'};
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.ChannelDetailsPanel);
            app.zLabel.Tooltip = {'Add Shift manually'};
            app.zLabel.Text = 'z';
            app.ChannelEnableCheckbox = uicheckbox(app.ChannelDetailsPanel);
            app.ChannelEnableCheckbox.ValueChangedFcn = createCallbackFcn(app, @ChannelEnableCheckboxValueChanged, true);
            app.ChannelEnableCheckbox.Tooltip = {'Turn selected Channel on/off'};
            app.ChannelEnableCheckbox.Text = 'On/Off';
            app.DBSCANCheckBox = uicheckbox(app.ChannelDetailsPanel);
            app.DBSCANCheckBox.ValueChangedFcn = createCallbackFcn(app, @DBSCANCheckBoxValueChanged, true);
            app.DBSCANCheckBox.Tooltip = {'Turn DBSCAN on/off for selected Data'};
            app.DBSCANCheckBox.Text = 'DBSCAN';
            app.DBSCANCheckBox.Value = true;
            app.ChannelFileLabel = uilabel(app.ChannelDetailsPanel);
            app.ChannelFileLabel.Text = '';
            app.ChannelNameLabel = uilabel(app.ChannelDetailsPanel);
            app.ChannelNameLabel.Text = '';
            app.LoadChannelButton = uibutton(app.FieldofViewMultiTab, 'push');
            app.LoadChannelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChannelButtonPushed, true);
            app.LoadChannelButton.Tooltip = {'Add additional Channels or Reload an existing Channel'};
            app.LoadChannelButton.Text = 'Add/Reload Channel';
            app.FieldofViewforMainDataandAdditionalChannelsLabel = uilabel(app.FieldofViewMultiTab);
            app.FieldofViewforMainDataandAdditionalChannelsLabel.Text = 'Field of View for Main Data and Additional Channels';
            app.DBSCANparametersareappliedtoallchannelsLabel = uilabel(app.FieldofViewMultiTab);
            app.DBSCANparametersareappliedtoallchannelsLabel.Text = '(DBSCAN parameters are applied to all channels)';
            app.ClusterAnalysisMultiTab = uitab(app.TabGroup);
            app.ClusterAnalysisMultiTab.Tooltip = {'Compare and analyze SINGLE CLUSTER data across multiple channels.'};
            app.ClusterAnalysisMultiTab.Title = 'Cluster Analysis (Multi)';
            app.ClusterAnalysisMultiTab.ButtonDownFcn = createCallbackFcn(app, @ClusterAnalysisMultiTabButtonDown, true);
            app.UIAxes5_2 = uiaxes(app.ClusterAnalysisMultiTab);
            app.ListBox_2 = uilistbox(app.ClusterAnalysisMultiTab);
            app.ListBox_2.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged2, true);
            app.ListBox_2.Tooltip = {'Select multiple Clusters together to generate Overlay'};
            app.ListBox_2.Value = {'Item 1'};
            app.EllipsoidFitCheckBox_2 = uicheckbox(app.ClusterAnalysisMultiTab);
            app.EllipsoidFitCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EllipsoidFitCheckBox_2ValueChanged, true);
            app.EllipsoidFitCheckBox_2.Tooltip = {'Click here for Fitting an Ellipsoid over the Point Cloud'};
            app.EllipsoidFitCheckBox_2.Text = 'Ellipsoid Fit';
            app.AlphaShapeCheckBox_2 = uicheckbox(app.ClusterAnalysisMultiTab);
            app.AlphaShapeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AlphaShapeCheckBox_2ValueChanged, true);
            app.AlphaShapeCheckBox_2.Tooltip = {'Click here for Fitting the alphaShape function over your Point Cloud'};
            app.AlphaShapeCheckBox_2.Text = 'Alpha Shape';
            app.AlphaRadiusSlider_2 = uislider(app.ClusterAnalysisMultiTab);
            app.AlphaRadiusSlider_2.Visible = 'off';
            app.AlphaRadiusSlider_2.Tooltip = {'Adjust AlphaRadius'};
            app.AlphaRadiusSlider_2.Value = 50;
            app.AlphaRadiusLabel_2 = uilabel(app.ClusterAnalysisMultiTab);
            app.AlphaRadiusLabel_2.Visible = 'off';
            app.AlphaRadiusLabel_2.Text = '';
            app.Ch2CheckBox = uicheckbox(app.ClusterAnalysisMultiTab);
            app.Ch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch2CheckBoxValueChanged, true);
            app.Ch2CheckBox.Visible = 'off';
            app.Ch2CheckBox.Text = 'Ch. 2';
            app.Ch3CheckBox = uicheckbox(app.ClusterAnalysisMultiTab);
            app.Ch3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch3CheckBoxValueChanged, true);
            app.Ch3CheckBox.Visible = 'off';
            app.Ch3CheckBox.Text = 'Ch. 3';
            app.Ch4CheckBox = uicheckbox(app.ClusterAnalysisMultiTab);
            app.Ch4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch4CheckBoxValueChanged, true);
            app.Ch4CheckBox.Visible = 'off';
            app.Ch4CheckBox.Text = 'Ch. 4';
            app.Ch5CheckBox = uicheckbox(app.ClusterAnalysisMultiTab);
            app.Ch5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch5CheckBoxValueChanged, true);
            app.Ch5CheckBox.Visible = 'off';
            app.Ch5CheckBox.Text = 'Ch. 5';
            app.SingleClusterAnalysisMultiChannelLabel = uilabel(app.ClusterAnalysisMultiTab);
            app.SingleClusterAnalysisMultiChannelLabel.Text = 'Single Cluster Analysis (Multi-Channel)';
            app.AnalyzesingleclustersinmultiplecolorsLabel = uilabel(app.ClusterAnalysisMultiTab);
            app.AnalyzesingleclustersinmultiplecolorsLabel.Text = 'Analyze single clusters in multiple colors';
            app.ChannelsonoffLabel = uilabel(app.ClusterAnalysisMultiTab);
            app.ChannelsonoffLabel.Text = {'Channels'; 'on/off:'};
            app.ConcentricalAnalysisTab = uitab(app.TabGroup);
            app.ConcentricalAnalysisTab.Tooltip = {'Generate line profiles and distance histograms to analyze spatial distribution.'};
            app.ConcentricalAnalysisTab.Title = 'Concentrical Analysis';
            app.UIAxes6 = uiaxes(app.ConcentricalAnalysisTab);
            app.MeanDistLabel = uilabel(app.ConcentricalAnalysisTab);
            app.MeanDistLabel.Text = '';
            app.ButtonGroup1 = uibuttongroup(app.ConcentricalAnalysisTab);
            app.ButtonGroup1.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup1SelectionChanged, true);
            app.locButton1 = uitogglebutton(app.ButtonGroup1);
            app.locButton1.Tooltip = {'Switch to Localizations'};
            app.locButton1.Text = 'Loc';
            app.locButton1.Value = true;
            app.trcButton1 = uitogglebutton(app.ButtonGroup1);
            app.trcButton1.Tooltip = {'Switch to Traces'};
            app.trcButton1.Text = 'Trc';
            app.ListBox2 = uilistbox(app.ConcentricalAnalysisTab);
            app.ListBox2.Multiselect = 'on';
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox2.Value = {'Item 1'};
            app.NormalizeIndividuallyCheckBox = uicheckbox(app.ConcentricalAnalysisTab);
            app.NormalizeIndividuallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeIndividuallyCheckBoxValueChanged, true);
            app.NormalizeIndividuallyCheckBox.Tooltip = {'Normalize Intensity to the Highest Signal'};
            app.NormalizeIndividuallyCheckBox.Text = 'Channel Norm';
            app.ConcentricalAnalysisLabel = uilabel(app.ConcentricalAnalysisTab);
            app.ConcentricalAnalysisLabel.Text = 'Concentrical Analysis';
            app.HistogramofMainDataandLineProfilesfromaddChannelsLabel = uilabel(app.ConcentricalAnalysisTab);
            app.HistogramofMainDataandLineProfilesfromaddChannelsLabel.Text = 'Histogram of Main Data and Line Profiles from add. Channels';
            app.PolarPlotTab = uitab(app.TabGroup);
            app.PolarPlotTab.Tooltip = {'Visualize polar plots to measure radial distribution from cluster centroids.'};
            app.PolarPlotTab.Title = 'Polar Plot';
            app.ButtonGroup = uibuttongroup(app.PolarPlotTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.locButton = uitogglebutton(app.ButtonGroup);
            app.locButton.Tooltip = {'Switch to Localizations'};
            app.locButton.Text = 'Loc';
            app.locButton.Value = true;
            app.trcButton = uitogglebutton(app.ButtonGroup);
            app.trcButton.Tooltip = {'Switch to Traces'};
            app.trcButton.Text = 'Trc';
            app.ListBox2_2 = uilistbox(app.PolarPlotTab);
            app.ListBox2_2.Multiselect = 'on';
            app.ListBox2_2.ValueChangedFcn = createCallbackFcn(app, @ListBox2_2ValueChanged, true);
            app.ListBox2_2.Value = {'Item 1'};
            app.Panel = uipanel(app.PolarPlotTab);
            app.ColorCheck = uicheckbox(app.PolarPlotTab);
            app.ColorCheck.ValueChangedFcn = createCallbackFcn(app, @ColorCheckValueChanged, true);
            app.ColorCheck.Tooltip = {'Adjust for a better Contrast'};
            app.ColorCheck.Text = 'Contrast';
            app.PolarPlotLabel = uilabel(app.PolarPlotTab);
            app.PolarPlotLabel.Text = 'Polar Plot';
            app.MeanDistLabel2 = uilabel(app.PolarPlotTab);
            app.MeanDistLabel2.Text = '';
            app.PolarPlotofMainDataandAdditionalChannelsLabel = uilabel(app.PolarPlotTab);
            app.PolarPlotofMainDataandAdditionalChannelsLabel.Text = 'Polar Plot of Main Data and Additional Channels';
            app.NumbersTab = uitab(app.TabGroup);
            app.NumbersTab.Tooltip = {'View numerical summaries of clusters (volume, surface area, etc.).'};
            app.NumbersTab.Title = 'Numbers';
            app.NumbersTab.ButtonDownFcn = createCallbackFcn(app, @NumbersTabButtonDown, true);
            app.UITable = uitable(app.NumbersTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.UITable.RowName = {};
            app.SingleClustersinNumbersLabel = uilabel(app.NumbersTab);
            app.SingleClustersinNumbersLabel.Text = 'Single Clusters in Numbers';
            app.NumbersmultiTab = uitab(app.TabGroup);
            app.NumbersmultiTab.Tooltip = {'Summarize localization counts and statistics across channels.'};
            app.NumbersmultiTab.Title = 'Numbers (multi)';
            app.NumbersmultiTab.ButtonDownFcn = createCallbackFcn(app, @NumbersmultiTabButtonDown, true);
            app.UITableMulti = uitable(app.NumbersmultiTab);
            app.UITableMulti.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.UITableMulti.RowName = {};
            app.SaveNumMultCSVButton = uibutton(app.NumbersmultiTab, 'push');
            app.SaveNumMultCSVButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNumMultCSVButtonPushed, true);
            app.SaveNumMultCSVButton.Tooltip = {'Table is saved as .csv'};
            app.SaveNumMultCSVButton.Text = 'Save Table';
            app.SingleClustersinNumbersmultichannelLabel = uilabel(app.NumbersmultiTab);
            app.SingleClustersinNumbersmultichannelLabel.Text = 'Single Clusters in Numbers (multi-channel)';
            app.AcqParametersTab = uitab(app.TabGroup);
            app.AcqParametersTab.Tooltip = {'Assess data quality with trace counts and temporal localization histograms.'};
            app.AcqParametersTab.Title = 'Acq. Parameters';
            app.AcqParametersTab.ButtonDownFcn = createCallbackFcn(app, @AcqParametersTabButtonDown, true);
            app.UIAxes3 = uiaxes(app.AcqParametersTab);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.AcqParametersTab);
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.Label_2 = uilabel(app.AcqParametersTab);
            app.Label_2.Text = '';
            app.LocalizationsperTraceLabel = uilabel(app.AcqParametersTab);
            app.LocalizationsperTraceLabel.Text = {'Localizations '; 'per Trace'};
            app.LocalizationsovertimeLabel = uilabel(app.AcqParametersTab);
            app.LocalizationsovertimeLabel.Text = {'Localizations '; 'over time'};
            app.MINFLUXAcquisitionParametersLabel = uilabel(app.AcqParametersTab);
            app.MINFLUXAcquisitionParametersLabel.Text = 'MINFLUX Acquisition Parameters';
            app.LocalizationPrecisionTab = uitab(app.TabGroup);
            app.LocalizationPrecisionTab.Tooltip = {'Calculate localization precision via standard deviations and error estimates.'};
            app.LocalizationPrecisionTab.Title = 'Localization Precision';
            app.LocalizationPrecisionTab.ButtonDownFcn = createCallbackFcn(app, @LocalizationPrecisionTabButtonDown, true);
            app.SaveLocPrecPDFButton = uibutton(app.LocalizationPrecisionTab, 'push');
            app.SaveLocPrecPDFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLocPrecPDFButtonPushed2, true);
            app.SaveLocPrecPDFButton.Tooltip = {'All displayed plots are saved as .png'};
            app.SaveLocPrecPDFButton.Text = 'Save as .png';
            app.LocalizationPrecisionofLoadedDataLabel = uilabel(app.LocalizationPrecisionTab);
            app.LocalizationPrecisionofLoadedDataLabel.Text = 'Localization Precision of Loaded Data';
            app.EnableExclusion = uicheckbox(app.DBSCAN);
            app.EnableExclusion.ValueChangedFcn = createCallbackFcn(app, @EnableExclusionValueChanged, true);
            app.EnableExclusion.Text = '';
            app.ExclNum = uispinner(app.DBSCAN);
            app.ExclNum.ValueChangedFcn = createCallbackFcn(app, @ExclNumValueChanged2, true);
            app.ExclNum.Tooltip = {'If active, only Clusters above this Value are displayed'};
            app.Label = uilabel(app.DBSCAN);
            app.Label.Text = '>=';
            app.Button = uibutton(app.DBSCAN, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'camera.png');
            app.Button.Tooltip = {'Camera Button allows you to save every generated Plot as PNG or PDF'};
            app.Button.Text = '';
            % Show the figure after all components are created
            app.DBSCAN.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matFLUX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DBSCAN)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DBSCAN)
        end
    end
end